classdef GUIImplementation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageProcessingAppUIFigure      matlab.ui.Figure
        ImageProcessingAPPPanel         matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        MappingButton                   matlab.ui.control.Button
        ConversionButton                matlab.ui.control.Button
        HistogramButton                 matlab.ui.control.Button
        ArithmeticButton                matlab.ui.control.Button
        SmoothingButton                 matlab.ui.control.Button
        EdgeDetectionButton             matlab.ui.control.Button
        SharpeningButton                matlab.ui.control.Button
        UnsharpenButton                 matlab.ui.control.Button
        ConversionWorkSpacePanel        matlab.ui.container.Panel
        UploadPhotoButton_2             matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        DirectMappingZeroOrderPanel_2   matlab.ui.container.Panel
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_10                       matlab.ui.control.UIAxes
        FactorEditField_3Label          matlab.ui.control.Label
        FactorEditField_3               matlab.ui.control.NumericEditField
        ResizeButton_5                  matlab.ui.control.Button
        DirectMappingOneOrderPanel_2    matlab.ui.container.Panel
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_12                       matlab.ui.control.UIAxes
        FactorEditField_4Label          matlab.ui.control.Label
        FactorEditField_4               matlab.ui.control.NumericEditField
        ResizeButton_6                  matlab.ui.control.Button
        ReverseMappingOneOrderPanel_2   matlab.ui.container.Panel
        UIAxes_13                       matlab.ui.control.UIAxes
        UIAxes_14                       matlab.ui.control.UIAxes
        ResizeButton_7                  matlab.ui.control.Button
        RowFactorEditField_3Label       matlab.ui.control.Label
        RowFactorEditField_3            matlab.ui.control.NumericEditField
        ColFactorEditField_3Label       matlab.ui.control.Label
        ColFactorEditField_3            matlab.ui.control.NumericEditField
        ReverseMappingZeroOrderPanel_2  matlab.ui.container.Panel
        UIAxes_15                       matlab.ui.control.UIAxes
        UIAxes_16                       matlab.ui.control.UIAxes
        ResizeButton_8                  matlab.ui.control.Button
        RowFactorEditField_4Label       matlab.ui.control.Label
        RowFactorEditField_4            matlab.ui.control.NumericEditField
        ColFactorEditField_4Label       matlab.ui.control.Label
        ColFactorEditField_4            matlab.ui.control.NumericEditField
        ConverttoGrayScalePanel         matlab.ui.container.Panel
        UIAxes_17                       matlab.ui.control.UIAxes
        UIAxes_18                       matlab.ui.control.UIAxes
        DecomposingButton               matlab.ui.control.Button
        LuminanceButton                 matlab.ui.control.Button
        AveragingButton                 matlab.ui.control.Button
        SingleChannelButton             matlab.ui.control.Button
        DesaturationButton              matlab.ui.control.Button
        EdgeDetectionWorkSpacePanel     matlab.ui.container.Panel
        UploadPhotoButton_6             matlab.ui.control.Button
        EditField_12                    matlab.ui.control.EditField
        histogramFunctionsPanel_6       matlab.ui.container.Panel
        UIAxes_55                       matlab.ui.control.UIAxes
        UIAxes_56                       matlab.ui.control.UIAxes
        FactorEditField_11Label         matlab.ui.control.Label
        FactorEditField_11              matlab.ui.control.NumericEditField
        ResizeButton_21                 matlab.ui.control.Button
        DirectMappingOneOrderPanel_6    matlab.ui.container.Panel
        UIAxes_57                       matlab.ui.control.UIAxes
        UIAxes_58                       matlab.ui.control.UIAxes
        FactorEditField_12Label         matlab.ui.control.Label
        FactorEditField_12              matlab.ui.control.NumericEditField
        ResizeButton_22                 matlab.ui.control.Button
        ReverseMappingOneOrderPanel_6   matlab.ui.container.Panel
        UIAxes_59                       matlab.ui.control.UIAxes
        UIAxes_60                       matlab.ui.control.UIAxes
        ResizeButton_23                 matlab.ui.control.Button
        RowFactorEditField_11Label      matlab.ui.control.Label
        RowFactorEditField_11           matlab.ui.control.NumericEditField
        ColFactorEditField_11Label      matlab.ui.control.Label
        ColFactorEditField_11           matlab.ui.control.NumericEditField
        ReverseMappingZeroOrderPanel_6  matlab.ui.container.Panel
        UIAxes_61                       matlab.ui.control.UIAxes
        UIAxes_62                       matlab.ui.control.UIAxes
        ResizeButton_24                 matlab.ui.control.Button
        RowFactorEditField_12Label      matlab.ui.control.Label
        RowFactorEditField_12           matlab.ui.control.NumericEditField
        ColFactorEditField_12Label      matlab.ui.control.Label
        ColFactorEditField_12           matlab.ui.control.NumericEditField
        ImageAdditionSubtractionPanel_3  matlab.ui.container.Panel
        UIAxes_63                       matlab.ui.control.UIAxes
        UIAxes_64                       matlab.ui.control.UIAxes
        OffsetPanel_6                   matlab.ui.container.Panel
        OffsetEditField_6Label          matlab.ui.control.Label
        OffsetEditField_6               matlab.ui.control.NumericEditField
        New_RangePanel_6                matlab.ui.container.Panel
        NEW_MAXEditField_6Label         matlab.ui.control.Label
        NEW_MAXEditField_6              matlab.ui.control.NumericEditField
        NEW_MINEditField_6Label         matlab.ui.control.Label
        NEW_MINEditField_6              matlab.ui.control.NumericEditField
        GammaPanel_6                    matlab.ui.container.Panel
        GammaEditField_6Label           matlab.ui.control.Label
        GammaEditField_6                matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.EditField
        EditField_14                    matlab.ui.control.EditField
        UIAxes_65                       matlab.ui.control.UIAxes
        BitsPanel_3                     matlab.ui.container.Panel
        NoOfBitsEditField_3Label        matlab.ui.control.Label
        NoOfBitsEditField_3             matlab.ui.control.NumericEditField
        EdgeDetectionPanel              matlab.ui.container.Panel
        OffsetPanel_7                   matlab.ui.container.Panel
        OffsetEditField_7Label          matlab.ui.control.Label
        OffsetEditField_7               matlab.ui.control.NumericEditField
        New_RangePanel_7                matlab.ui.container.Panel
        NEW_MAXEditField_7Label         matlab.ui.control.Label
        NEW_MAXEditField_7              matlab.ui.control.NumericEditField
        NEW_MINEditField_7Label         matlab.ui.control.Label
        NEW_MINEditField_7              matlab.ui.control.NumericEditField
        GammaPanel_7                    matlab.ui.container.Panel
        GammaEditField_7Label           matlab.ui.control.Label
        GammaEditField_7                matlab.ui.control.NumericEditField
        MaskPanel_2                     matlab.ui.container.Panel
        MaskSizeEditField_2Label        matlab.ui.control.Label
        MaskSizeEditField_2             matlab.ui.control.NumericEditField
        SigmaPanel_2                    matlab.ui.container.Panel
        SigmaEditField_2Label           matlab.ui.control.Label
        SigmaEditField_2                matlab.ui.control.NumericEditField
        UIAxes_66                       matlab.ui.control.UIAxes
        UIAxes_67                       matlab.ui.control.UIAxes
        ApplyButton                     matlab.ui.control.Button
        SharpeningWorkSpacePanel        matlab.ui.container.Panel
        UploadPhotoButton_7             matlab.ui.control.Button
        EditField_15                    matlab.ui.control.EditField
        histogramFunctionsPanel_7       matlab.ui.container.Panel
        UIAxes_68                       matlab.ui.control.UIAxes
        UIAxes_69                       matlab.ui.control.UIAxes
        FactorEditField_13Label         matlab.ui.control.Label
        FactorEditField_13              matlab.ui.control.NumericEditField
        ResizeButton_25                 matlab.ui.control.Button
        DirectMappingOneOrderPanel_7    matlab.ui.container.Panel
        UIAxes_70                       matlab.ui.control.UIAxes
        UIAxes_71                       matlab.ui.control.UIAxes
        FactorEditField_14Label         matlab.ui.control.Label
        FactorEditField_14              matlab.ui.control.NumericEditField
        ResizeButton_26                 matlab.ui.control.Button
        ReverseMappingOneOrderPanel_7   matlab.ui.container.Panel
        UIAxes_72                       matlab.ui.control.UIAxes
        UIAxes_73                       matlab.ui.control.UIAxes
        ResizeButton_27                 matlab.ui.control.Button
        RowFactorEditField_13Label      matlab.ui.control.Label
        RowFactorEditField_13           matlab.ui.control.NumericEditField
        ColFactorEditField_13Label      matlab.ui.control.Label
        ColFactorEditField_13           matlab.ui.control.NumericEditField
        ReverseMappingZeroOrderPanel_7  matlab.ui.container.Panel
        UIAxes_74                       matlab.ui.control.UIAxes
        UIAxes_75                       matlab.ui.control.UIAxes
        ResizeButton_28                 matlab.ui.control.Button
        RowFactorEditField_14Label      matlab.ui.control.Label
        RowFactorEditField_14           matlab.ui.control.NumericEditField
        ColFactorEditField_14Label      matlab.ui.control.Label
        ColFactorEditField_14           matlab.ui.control.NumericEditField
        ImageAdditionSubtractionPanel_4  matlab.ui.container.Panel
        UIAxes_76                       matlab.ui.control.UIAxes
        UIAxes_77                       matlab.ui.control.UIAxes
        OffsetPanel_8                   matlab.ui.container.Panel
        OffsetEditField_8Label          matlab.ui.control.Label
        OffsetEditField_8               matlab.ui.control.NumericEditField
        New_RangePanel_8                matlab.ui.container.Panel
        NEW_MAXEditField_8Label         matlab.ui.control.Label
        NEW_MAXEditField_8              matlab.ui.control.NumericEditField
        NEW_MINEditField_8Label         matlab.ui.control.Label
        NEW_MINEditField_8              matlab.ui.control.NumericEditField
        GammaPanel_8                    matlab.ui.container.Panel
        GammaEditField_8Label           matlab.ui.control.Label
        GammaEditField_8                matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.EditField
        EditField_17                    matlab.ui.control.EditField
        UIAxes_78                       matlab.ui.control.UIAxes
        BitsPanel_4                     matlab.ui.container.Panel
        NoOfBitsEditField_4Label        matlab.ui.control.Label
        NoOfBitsEditField_4             matlab.ui.control.NumericEditField
        SharpeningPanel                 matlab.ui.container.Panel
        OffsetPanel_9                   matlab.ui.container.Panel
        OffsetEditField_9Label          matlab.ui.control.Label
        OffsetEditField_9               matlab.ui.control.NumericEditField
        New_RangePanel_9                matlab.ui.container.Panel
        NEW_MAXEditField_9Label         matlab.ui.control.Label
        NEW_MAXEditField_9              matlab.ui.control.NumericEditField
        NEW_MINEditField_9Label         matlab.ui.control.Label
        NEW_MINEditField_9              matlab.ui.control.NumericEditField
        GammaPanel_9                    matlab.ui.container.Panel
        GammaEditField_9Label           matlab.ui.control.Label
        GammaEditField_9                matlab.ui.control.NumericEditField
        MaskPanel_3                     matlab.ui.container.Panel
        MaskSizeEditField_3Label        matlab.ui.control.Label
        MaskSizeEditField_3             matlab.ui.control.NumericEditField
        SigmaPanel_3                    matlab.ui.container.Panel
        SigmaEditField_3Label           matlab.ui.control.Label
        SigmaEditField_3                matlab.ui.control.NumericEditField
        UIAxes_79                       matlab.ui.control.UIAxes
        UIAxes_80                       matlab.ui.control.UIAxes
        ApplyButton_2                   matlab.ui.control.Button
        UnsharpenMaskingWorkSpacePanel  matlab.ui.container.Panel
        UploadPhotoButton_8             matlab.ui.control.Button
        EditField_18                    matlab.ui.control.EditField
        histogramFunctionsPanel_8       matlab.ui.container.Panel
        UIAxes_81                       matlab.ui.control.UIAxes
        UIAxes_82                       matlab.ui.control.UIAxes
        FactorEditField_15Label         matlab.ui.control.Label
        FactorEditField_15              matlab.ui.control.NumericEditField
        ResizeButton_29                 matlab.ui.control.Button
        DirectMappingOneOrderPanel_8    matlab.ui.container.Panel
        UIAxes_83                       matlab.ui.control.UIAxes
        UIAxes_84                       matlab.ui.control.UIAxes
        FactorEditField_16Label         matlab.ui.control.Label
        FactorEditField_16              matlab.ui.control.NumericEditField
        ResizeButton_30                 matlab.ui.control.Button
        ReverseMappingOneOrderPanel_8   matlab.ui.container.Panel
        UIAxes_85                       matlab.ui.control.UIAxes
        UIAxes_86                       matlab.ui.control.UIAxes
        ResizeButton_31                 matlab.ui.control.Button
        RowFactorEditField_15Label      matlab.ui.control.Label
        RowFactorEditField_15           matlab.ui.control.NumericEditField
        ColFactorEditField_15Label      matlab.ui.control.Label
        ColFactorEditField_15           matlab.ui.control.NumericEditField
        ReverseMappingZeroOrderPanel_8  matlab.ui.container.Panel
        UIAxes_87                       matlab.ui.control.UIAxes
        UIAxes_88                       matlab.ui.control.UIAxes
        ResizeButton_32                 matlab.ui.control.Button
        RowFactorEditField_16Label      matlab.ui.control.Label
        RowFactorEditField_16           matlab.ui.control.NumericEditField
        ColFactorEditField_16Label      matlab.ui.control.Label
        ColFactorEditField_16           matlab.ui.control.NumericEditField
        ImageAdditionSubtractionPanel_5  matlab.ui.container.Panel
        UIAxes_89                       matlab.ui.control.UIAxes
        UIAxes_90                       matlab.ui.control.UIAxes
        OffsetPanel_10                  matlab.ui.container.Panel
        OffsetEditField_10Label         matlab.ui.control.Label
        OffsetEditField_10              matlab.ui.control.NumericEditField
        New_RangePanel_10               matlab.ui.container.Panel
        NEW_MAXEditField_10Label        matlab.ui.control.Label
        NEW_MAXEditField_10             matlab.ui.control.NumericEditField
        NEW_MINEditField_10Label        matlab.ui.control.Label
        NEW_MINEditField_10             matlab.ui.control.NumericEditField
        GammaPanel_10                   matlab.ui.container.Panel
        GammaEditField_10Label          matlab.ui.control.Label
        GammaEditField_10               matlab.ui.control.NumericEditField
        EditField_19                    matlab.ui.control.EditField
        EditField_20                    matlab.ui.control.EditField
        UIAxes_91                       matlab.ui.control.UIAxes
        BitsPanel_5                     matlab.ui.container.Panel
        NoOfBitsEditField_5Label        matlab.ui.control.Label
        NoOfBitsEditField_5             matlab.ui.control.NumericEditField
        UnsharpenMaskingPanel           matlab.ui.container.Panel
        OffsetPanel_11                  matlab.ui.container.Panel
        OffsetEditField_11Label         matlab.ui.control.Label
        OffsetEditField_11              matlab.ui.control.NumericEditField
        New_RangePanel_11               matlab.ui.container.Panel
        NEW_MAXEditField_11Label        matlab.ui.control.Label
        NEW_MAXEditField_11             matlab.ui.control.NumericEditField
        NEW_MINEditField_11Label        matlab.ui.control.Label
        NEW_MINEditField_11             matlab.ui.control.NumericEditField
        GammaPanel_11                   matlab.ui.container.Panel
        GammaEditField_11Label          matlab.ui.control.Label
        GammaEditField_11               matlab.ui.control.NumericEditField
        MaskPanel_4                     matlab.ui.container.Panel
        MaskSizeEditField_4Label        matlab.ui.control.Label
        MaskSizeEditField_4             matlab.ui.control.NumericEditField
        SigmaPanel_4                    matlab.ui.container.Panel
        SigmaEditField_4Label           matlab.ui.control.Label
        SigmaEditField_4                matlab.ui.control.NumericEditField
        UIAxes_92                       matlab.ui.control.UIAxes
        UIAxes_93                       matlab.ui.control.UIAxes
        ApplyButton_3                   matlab.ui.control.Button
        SigmaLabel                      matlab.ui.control.Label
        SigmaEditField_5                matlab.ui.control.NumericEditField
        MappingWorkSpacePanel           matlab.ui.container.Panel
        UploadPhotoButton               matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        DirectMappingZeroOrderPanel     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        FactorEditFieldLabel            matlab.ui.control.Label
        FactorEditField                 matlab.ui.control.NumericEditField
        ResizeButton                    matlab.ui.control.Button
        ReverseMappingZeroOrderButton   matlab.ui.control.Button
        DirectMappingOneOrderButton     matlab.ui.control.Button
        DirectMappingZeroOrderButton    matlab.ui.control.Button
        ReverseMappingOneOrderButton_4  matlab.ui.control.Button
        DirectMappingOneOrderPanel      matlab.ui.container.Panel
        FactorEditField_2Label          matlab.ui.control.Label
        FactorEditField_2               matlab.ui.control.NumericEditField
        ResizeButton_2                  matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        ReverseMappingZeroOrderPanel    matlab.ui.container.Panel
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        ResizeButton_3                  matlab.ui.control.Button
        RowFactorEditFieldLabel         matlab.ui.control.Label
        RowFactorEditField              matlab.ui.control.NumericEditField
        ColFactorEditFieldLabel         matlab.ui.control.Label
        ColFactorEditField              matlab.ui.control.NumericEditField
        ReverseMappingOneOrderPanel     matlab.ui.container.Panel
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        ResizeButton_4                  matlab.ui.control.Button
        RowFactorEditField_2Label       matlab.ui.control.Label
        RowFactorEditField_2            matlab.ui.control.NumericEditField
        ColFactorEditField_2Label       matlab.ui.control.Label
        ColFactorEditField_2            matlab.ui.control.NumericEditField
        HistogramWorkSpacePanel         matlab.ui.container.Panel
        UploadPhotoButton_3             matlab.ui.control.Button
        EditField_3                     matlab.ui.control.EditField
        histogramFunctionsPanel_3       matlab.ui.container.Panel
        UIAxes_19                       matlab.ui.control.UIAxes
        UIAxes_20                       matlab.ui.control.UIAxes
        FactorEditField_5Label          matlab.ui.control.Label
        FactorEditField_5               matlab.ui.control.NumericEditField
        ResizeButton_9                  matlab.ui.control.Button
        DirectMappingOneOrderPanel_3    matlab.ui.container.Panel
        UIAxes_21                       matlab.ui.control.UIAxes
        UIAxes_22                       matlab.ui.control.UIAxes
        FactorEditField_6Label          matlab.ui.control.Label
        FactorEditField_6               matlab.ui.control.NumericEditField
        ResizeButton_10                 matlab.ui.control.Button
        ReverseMappingOneOrderPanel_3   matlab.ui.container.Panel
        UIAxes_23                       matlab.ui.control.UIAxes
        UIAxes_24                       matlab.ui.control.UIAxes
        ResizeButton_11                 matlab.ui.control.Button
        RowFactorEditField_5Label       matlab.ui.control.Label
        RowFactorEditField_5            matlab.ui.control.NumericEditField
        ColFactorEditField_5Label       matlab.ui.control.Label
        ColFactorEditField_5            matlab.ui.control.NumericEditField
        ReverseMappingZeroOrderPanel_3  matlab.ui.container.Panel
        UIAxes_25                       matlab.ui.control.UIAxes
        UIAxes_26                       matlab.ui.control.UIAxes
        ResizeButton_12                 matlab.ui.control.Button
        RowFactorEditField_6Label       matlab.ui.control.Label
        RowFactorEditField_6            matlab.ui.control.NumericEditField
        ColFactorEditField_6Label       matlab.ui.control.Label
        ColFactorEditField_6            matlab.ui.control.NumericEditField
        HistogramPanel                  matlab.ui.container.Panel
        UIAxes_27                       matlab.ui.control.UIAxes
        UIAxes_28                       matlab.ui.control.UIAxes
        OffsetPanel                     matlab.ui.container.Panel
        OffsetEditFieldLabel            matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.NumericEditField
        New_RangePanel                  matlab.ui.container.Panel
        NEW_MAXEditFieldLabel           matlab.ui.control.Label
        NEW_MAXEditField                matlab.ui.control.NumericEditField
        NEW_MINEditFieldLabel           matlab.ui.control.Label
        NEW_MINEditField                matlab.ui.control.NumericEditField
        GammaPanel                      matlab.ui.container.Panel
        GammaEditFieldLabel             matlab.ui.control.Label
        GammaEditField                  matlab.ui.control.NumericEditField
        ApplyButton_5                   matlab.ui.control.Button
        ApplyButton_6                   matlab.ui.control.Button
        ApplyButton_7                   matlab.ui.control.Button
        HistogramMatchingPanel          matlab.ui.container.Panel
        UIAxes_94                       matlab.ui.control.UIAxes
        UIAxes_95                       matlab.ui.control.UIAxes
        OffsetPanel_12                  matlab.ui.container.Panel
        OffsetEditField_12Label         matlab.ui.control.Label
        OffsetEditField_12              matlab.ui.control.NumericEditField
        New_RangePanel_12               matlab.ui.container.Panel
        NEW_MAXEditField_12Label        matlab.ui.control.Label
        NEW_MAXEditField_12             matlab.ui.control.NumericEditField
        NEW_MINEditField_12Label        matlab.ui.control.Label
        NEW_MINEditField_12             matlab.ui.control.NumericEditField
        GammaPanel_12                   matlab.ui.container.Panel
        GammaEditField_12Label          matlab.ui.control.Label
        GammaEditField_12               matlab.ui.control.NumericEditField
        UIAxes_96                       matlab.ui.control.UIAxes
        UIAxes_97                       matlab.ui.control.UIAxes
        UIAxes_98                       matlab.ui.control.UIAxes
        UIAxes_99                       matlab.ui.control.UIAxes
        UploadRefPhotoButton            matlab.ui.control.Button
        EditField_4                     matlab.ui.control.EditField
        ApplyButton_4                   matlab.ui.control.Button
        HistogramEqualizationButton     matlab.ui.control.Button
        DrawingHistogramButton          matlab.ui.control.Button
        HistogramMatchingButton         matlab.ui.control.Button
        ContrastLow_or_HighButton       matlab.ui.control.Button
        PowerLawTransformationsButton   matlab.ui.control.Button
        BrightnessadjustmentButton      matlab.ui.control.Button
        ImageArithmeticWorkSpacePanel   matlab.ui.container.Panel
        UploadPhotoButton_4             matlab.ui.control.Button
        EditField_5                     matlab.ui.control.EditField
        histogramFunctionsPanel_4       matlab.ui.container.Panel
        UIAxes_29                       matlab.ui.control.UIAxes
        UIAxes_30                       matlab.ui.control.UIAxes
        FactorEditField_7Label          matlab.ui.control.Label
        FactorEditField_7               matlab.ui.control.NumericEditField
        ResizeButton_13                 matlab.ui.control.Button
        DirectMappingOneOrderPanel_4    matlab.ui.container.Panel
        UIAxes_31                       matlab.ui.control.UIAxes
        UIAxes_32                       matlab.ui.control.UIAxes
        FactorEditField_8Label          matlab.ui.control.Label
        FactorEditField_8               matlab.ui.control.NumericEditField
        ResizeButton_14                 matlab.ui.control.Button
        ReverseMappingOneOrderPanel_4   matlab.ui.container.Panel
        UIAxes_33                       matlab.ui.control.UIAxes
        UIAxes_34                       matlab.ui.control.UIAxes
        ResizeButton_15                 matlab.ui.control.Button
        RowFactorEditField_7Label       matlab.ui.control.Label
        RowFactorEditField_7            matlab.ui.control.NumericEditField
        ColFactorEditField_7Label       matlab.ui.control.Label
        ColFactorEditField_7            matlab.ui.control.NumericEditField
        ReverseMappingZeroOrderPanel_4  matlab.ui.container.Panel
        UIAxes_35                       matlab.ui.control.UIAxes
        UIAxes_36                       matlab.ui.control.UIAxes
        ResizeButton_16                 matlab.ui.control.Button
        RowFactorEditField_8Label       matlab.ui.control.Label
        RowFactorEditField_8            matlab.ui.control.NumericEditField
        ColFactorEditField_8Label       matlab.ui.control.Label
        ColFactorEditField_8            matlab.ui.control.NumericEditField
        ImageAdditionSubtractionPanel   matlab.ui.container.Panel
        UIAxes_37                       matlab.ui.control.UIAxes
        OffsetPanel_2                   matlab.ui.container.Panel
        OffsetEditField_2Label          matlab.ui.control.Label
        OffsetEditField_2               matlab.ui.control.NumericEditField
        New_RangePanel_2                matlab.ui.container.Panel
        NEW_MAXEditField_2Label         matlab.ui.control.Label
        NEW_MAXEditField_2              matlab.ui.control.NumericEditField
        NEW_MINEditField_2Label         matlab.ui.control.Label
        NEW_MINEditField_2              matlab.ui.control.NumericEditField
        GammaPanel_2                    matlab.ui.container.Panel
        GammaEditField_2Label           matlab.ui.control.Label
        GammaEditField_2                matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.EditField
        UploadPhoto_2Button             matlab.ui.control.Button
        EditField_7                     matlab.ui.control.EditField
        ApplyButton_8                   matlab.ui.control.Button
        UIAxes_39                       matlab.ui.control.UIAxes
        UIAxes_38                       matlab.ui.control.UIAxes
        ApplyButton_9                   matlab.ui.control.Button
        SubtractionButton               matlab.ui.control.Button
        AdditionButton                  matlab.ui.control.Button
        ImageNegativeButton             matlab.ui.control.Button
        QuantizationButton              matlab.ui.control.Button
        ImageArithmeticPanel            matlab.ui.container.Panel
        OffsetPanel_3                   matlab.ui.container.Panel
        OffsetEditField_3Label          matlab.ui.control.Label
        OffsetEditField_3               matlab.ui.control.NumericEditField
        New_RangePanel_3                matlab.ui.container.Panel
        NEW_MAXEditField_3Label         matlab.ui.control.Label
        NEW_MAXEditField_3              matlab.ui.control.NumericEditField
        NEW_MINEditField_3Label         matlab.ui.control.Label
        NEW_MINEditField_3              matlab.ui.control.NumericEditField
        GammaPanel_3                    matlab.ui.container.Panel
        GammaEditField_3Label           matlab.ui.control.Label
        GammaEditField_3                matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.EditField
        BitsPanel                       matlab.ui.container.Panel
        NoOfBitsEditFieldLabel          matlab.ui.control.Label
        NoOfBitsEditField               matlab.ui.control.NumericEditField
        ApplyButton_10                  matlab.ui.control.Button
        UIAxes_40                       matlab.ui.control.UIAxes
        UIAxes_41                       matlab.ui.control.UIAxes
        SmoothingWorkSpacePanel         matlab.ui.container.Panel
        UploadPhotoButton_5             matlab.ui.control.Button
        EditField_9                     matlab.ui.control.EditField
        histogramFunctionsPanel_5       matlab.ui.container.Panel
        UIAxes_42                       matlab.ui.control.UIAxes
        UIAxes_43                       matlab.ui.control.UIAxes
        FactorEditField_9Label          matlab.ui.control.Label
        FactorEditField_9               matlab.ui.control.NumericEditField
        ResizeButton_17                 matlab.ui.control.Button
        DirectMappingOneOrderPanel_5    matlab.ui.container.Panel
        UIAxes_44                       matlab.ui.control.UIAxes
        UIAxes_45                       matlab.ui.control.UIAxes
        FactorEditField_10Label         matlab.ui.control.Label
        FactorEditField_10              matlab.ui.control.NumericEditField
        ResizeButton_18                 matlab.ui.control.Button
        ReverseMappingOneOrderPanel_5   matlab.ui.container.Panel
        UIAxes_46                       matlab.ui.control.UIAxes
        UIAxes_47                       matlab.ui.control.UIAxes
        ResizeButton_19                 matlab.ui.control.Button
        RowFactorEditField_9Label       matlab.ui.control.Label
        RowFactorEditField_9            matlab.ui.control.NumericEditField
        ColFactorEditField_9Label       matlab.ui.control.Label
        ColFactorEditField_9            matlab.ui.control.NumericEditField
        ReverseMappingZeroOrderPanel_5  matlab.ui.container.Panel
        UIAxes_48                       matlab.ui.control.UIAxes
        UIAxes_49                       matlab.ui.control.UIAxes
        ResizeButton_20                 matlab.ui.control.Button
        RowFactorEditField_10Label      matlab.ui.control.Label
        RowFactorEditField_10           matlab.ui.control.NumericEditField
        ColFactorEditField_10Label      matlab.ui.control.Label
        ColFactorEditField_10           matlab.ui.control.NumericEditField
        ImageAdditionSubtractionPanel_2  matlab.ui.container.Panel
        UIAxes_50                       matlab.ui.control.UIAxes
        UIAxes_51                       matlab.ui.control.UIAxes
        OffsetPanel_4                   matlab.ui.container.Panel
        OffsetEditField_4Label          matlab.ui.control.Label
        OffsetEditField_4               matlab.ui.control.NumericEditField
        New_RangePanel_4                matlab.ui.container.Panel
        NEW_MAXEditField_4Label         matlab.ui.control.Label
        NEW_MAXEditField_4              matlab.ui.control.NumericEditField
        NEW_MINEditField_4Label         matlab.ui.control.Label
        NEW_MINEditField_4              matlab.ui.control.NumericEditField
        GammaPanel_4                    matlab.ui.container.Panel
        GammaEditField_4Label           matlab.ui.control.Label
        GammaEditField_4                matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.EditField
        EditField_11                    matlab.ui.control.EditField
        UIAxes_52                       matlab.ui.control.UIAxes
        WeightedFilterButton            matlab.ui.control.Button
        MeanFilterButton                matlab.ui.control.Button
        MedianFilterButton              matlab.ui.control.Button
        MaxFilterButton                 matlab.ui.control.Button
        BitsPanel_2                     matlab.ui.container.Panel
        NoOfBitsEditField_2Label        matlab.ui.control.Label
        NoOfBitsEditField_2             matlab.ui.control.NumericEditField
        MinFilterButton                 matlab.ui.control.Button
        SmoothingPanel                  matlab.ui.container.Panel
        UIAxes_53                       matlab.ui.control.UIAxes
        UIAxes_54                       matlab.ui.control.UIAxes
        OffsetPanel_5                   matlab.ui.container.Panel
        OffsetEditField_5Label          matlab.ui.control.Label
        OffsetEditField_5               matlab.ui.control.NumericEditField
        New_RangePanel_5                matlab.ui.container.Panel
        NEW_MAXEditField_5Label         matlab.ui.control.Label
        NEW_MAXEditField_5              matlab.ui.control.NumericEditField
        NEW_MINEditField_5Label         matlab.ui.control.Label
        NEW_MINEditField_5              matlab.ui.control.NumericEditField
        GammaPanel_5                    matlab.ui.container.Panel
        GammaEditField_5Label           matlab.ui.control.Label
        GammaEditField_5                matlab.ui.control.NumericEditField
        MaskPanel                       matlab.ui.container.Panel
        MaskSizeLabel                   matlab.ui.control.Label
        MaskSizeEditField               matlab.ui.control.NumericEditField
        SigmaPanel                      matlab.ui.container.Panel
        SigmaEditFieldLabel             matlab.ui.control.Label
        SigmaEditField                  matlab.ui.control.NumericEditField
        ApplyButton_11                  matlab.ui.control.Button
        ApplyButton_12                  matlab.ui.control.Button
        ApplyButton_13                  matlab.ui.control.Button
        ApplyButton_14                  matlab.ui.control.Button
        ApplyButton_15                  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: UploadPhotoButton
        function UploadPhotoButtonPushed(app, event)
        
       [filename, pathname] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an image file');
      
    if isequal(filename, 0) || isequal(pathname, 0)
        % User canceled the file selection
        return;
    end
    
     fullname = fullfile(pathname, filename);
    app.EditField.Value = fullname; % Update EditField with the file path
    
    [~, ~, ext] = fileparts(fullname); % Extract file extension
    supportedFormats = {'.jpg', '.jpeg', '.png', '.bmp'}; % Add more if needed
    if ~any(strcmpi(ext, supportedFormats))
        errordlg('Unsupported file format. Please select a JPEG or PNG image.', 'Unsupported Format');
        return;
    end
    
    try
        im = imread(fullname);
        imshow(im,'Parent', app.UIAxes); % Display the image in UIAxes
         imshow(im,'Parent', app.UIAxes_3);
         imshow(im,'Parent', app.UIAxes_5);
         imshow(im,'Parent', app.UIAxes_7);
         
         % Make sure the figure is visible
        set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);
         
        
    catch ME
        errordlg(['Error reading the image file: ' ME.message], 'Image Read Error');
    end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Close request function: ImageProcessingAppUIFigure
        function ImageProcessingAppUIFigureCloseRequest(app, event)
        
             % This function is called when the UI figure is requested to close
    
             %% Close the UI figure
    closereq;
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
             % Get the factor value and input image path
        scaleFactor = app.FactorEditField.Value;
        fullname = app.EditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
        outputImage = DirectMappingZeroOrder(fullname, scaleFactor);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_2);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_2);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_2.DataAspectRatio = [1 1 1];
            
            % Adjust the size of the axes to match the size of the image
       
            
        end

        % Button pushed function: DirectMappingZeroOrderButton
        function DirectMappingZeroOrderButtonPushed(app, event)
             app.DirectMappingOneOrderPanel.Visible='off';
             app.ReverseMappingZeroOrderPanel.Visible='off';
             app.ReverseMappingOneOrderPanel.Visible='off';
            app.DirectMappingZeroOrderPanel.Visible = 'on';
        end

        % Size changed function: MappingWorkSpacePanel
        function MappingWorkSpacePanelSizeChanged(app, event)
            position = app.MappingWorkSpacePanel.Position;
            
        end

        % Button pushed function: ResizeButton_2
        function ResizeButton_2Pushed(app, event)
          scaleFactor = app.FactorEditField_2.Value;
        fullname = app.EditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
        outputImage = DirectMappingOneOrder(fullname, scaleFactor);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_4);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_4);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_4.DataAspectRatio = [1 1 1];
            
            % Adjust the size of the axes to match the size of the image
       
        end

        % Button pushed function: DirectMappingOneOrderButton
        function DirectMappingOneOrderButtonPushed(app, event)
            app.DirectMappingZeroOrderPanel.Visible='off';
            app.ReverseMappingZeroOrderPanel.Visible='off';
            app.ReverseMappingOneOrderPanel.Visible='off';
            app.DirectMappingOneOrderPanel.Visible='on';
            
            
        end

        % Button pushed function: ResizeButton_3
        function ResizeButton_3Pushed(app, event)
              % Get the factor value and input image path
        scaleFactor_R = app.RowFactorEditField.Value;
        scaleFactor_C = app.ColFactorEditField.Value;
        fullname = app.EditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
        outputImage = ReverseMappingZeroOrder(fullname, scaleFactor_R,scaleFactor_C);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_6);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_6);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_6.DataAspectRatio = [1 1 1];
            
            % Adjust the size of the axes to match the size of the image
        end

        % Button pushed function: ReverseMappingZeroOrderButton
        function ReverseMappingZeroOrderButtonPushed(app, event)
            app.DirectMappingZeroOrderPanel.Visible='off';
            app.DirectMappingOneOrderPanel.Visible='off';
            app.ReverseMappingOneOrderPanel.Visible='off';
            app.ReverseMappingZeroOrderPanel.Visible='on';
        end

        % Button pushed function: ResizeButton_4
        function ResizeButton_4Pushed(app, event)
               % Get the factor value and input image path
        scaleFactor_R = app.RowFactorEditField_2.Value;
        scaleFactor_C = app.ColFactorEditField_2.Value;
        fullname = app.EditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
        outputImage = ReverseMappingOneOrder(fullname, scaleFactor_R,scaleFactor_C);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_8);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_8);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_8.DataAspectRatio = [1 1 1];
            
            % Adjust the size of the axes to match the size of the image
        end

        % Button pushed function: ReverseMappingOneOrderButton_4
        function ReverseMappingOneOrderButton_4Pushed(app, event)
            app.DirectMappingZeroOrderPanel.Visible='off';
            app.DirectMappingOneOrderPanel.Visible='off';
            app.ReverseMappingZeroOrderPanel.Visible='off';
            app.ReverseMappingOneOrderPanel.Visible='on';
        end

        % Button pushed function: MappingButton
        function MappingButtonPushed(app, event)

            app.ConversionWorkSpacePanel.Visible='off';
            app.HistogramWorkSpacePanel.Visible='off';
            app.ImageArithmeticWorkSpacePanel.Visible='off';
            app.SmoothingWorkSpacePanel.Visible='off';
            app.EdgeDetectionWorkSpacePanel.Visible='off';
            app.SharpeningWorkSpacePanel.Visible='off';
            app.UnsharpenMaskingWorkSpacePanel.Visible='off';
            app.MappingWorkSpacePanel.Visible='on';
            
        end

        % Button pushed function: SingleChannelButton
        function SingleChannelButtonPushed(app, event)

        fullname = app.EditField_2.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
        outputImage = graySingleChannel(fullname);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_18);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_18);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_18.DataAspectRatio = [1 1 1];
            
            % Adjust the size of the axes to match the size of the image
        end

        % Button pushed function: UploadPhotoButton_2
        function UploadPhotoButton_2Pushed(app, event)
                 [filename, pathname] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an image file');
    if isequal(filename, 0) || isequal(pathname, 0)
        % User canceled the file selection
        return;
    end
    
     fullname = fullfile(pathname, filename);
    app.EditField_2.Value = fullname; % Update EditField with the file path
    
    [~, ~, ext] = fileparts(fullname); % Extract file extension
    supportedFormats = {'.jpg', '.jpeg', '.png', '.bmp'}; % Add more if needed
    if ~any(strcmpi(ext, supportedFormats))
        errordlg('Unsupported file format. Please select a JPEG or PNG image.', 'Unsupported Format');
        return;
    end
    
    try
        im = imread(fullname);
  
         imshow(im,'Parent', app.UIAxes_17);
         set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);
        
    catch ME
        errordlg(['Error reading the image file: ' ME.message], 'Image Read Error');
    end
        end

        % Button pushed function: AveragingButton
        function AveragingButtonPushed(app, event)
      

        fullname = app.EditField_2.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
        outputImage = grayAveraging(fullname);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_18);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_18);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_18.DataAspectRatio = [1 1 1];
            
            % Adjust the size of the axes to match the size of the image
        end

        % Button pushed function: LuminanceButton
        function LuminanceButtonPushed(app, event)
            fullname = app.EditField_2.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             inputImage=imread(fullname);
        outputImage = grayLuminance(inputImage);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_18);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_18);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_18.DataAspectRatio = [1 1 1];
            
            % Adjust the size of the axes to match the size of the image
        end

        % Button pushed function: DesaturationButton
        function DesaturationButtonPushed(app, event)
               fullname = app.EditField_2.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
        outputImage = grayDesaturation(fullname);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_18);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_18);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_18.DataAspectRatio = [1 1 1];
            
            % Adjust the size of the axes to match the size of the image
        end

        % Button pushed function: DecomposingButton
        function DecomposingButtonPushed(app, event)
               fullname = app.EditField_2.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
        outputImage = grayDecomposing(fullname);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_18);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_18);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_18.DataAspectRatio = [1 1 1];
            
            % Adjust the size of the axes to match the size of the image
        end

        % Button pushed function: ConversionButton
        function ConversionButtonPushed(app, event)
            app.MappingWorkSpacePanel.Visible='off';
            app.HistogramWorkSpacePanel.Visible='off';
            app.ImageArithmeticWorkSpacePanel.Visible='off';
            app.SmoothingWorkSpacePanel.Visible='off';
            app.EdgeDetectionWorkSpacePanel.Visible='off';
            app.SharpeningWorkSpacePanel.Visible='off';
            app.UnsharpenMaskingWorkSpacePanel.Visible='off';
            app.ConversionWorkSpacePanel.Visible='on';
        end

        % Button pushed function: UploadPhotoButton_3
        function UploadPhotoButton_3Pushed(app, event)

            [filename, pathname] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an image file');
    if isequal(filename, 0) || isequal(pathname, 0)
        % User canceled the file selection
        return;
    end
    
     fullname = fullfile(pathname, filename);
    app.EditField_3.Value = fullname; % Update EditField with the file path
    
    [~, ~, ext] = fileparts(fullname); % Extract file extension
    supportedFormats = {'.jpg', '.jpeg', '.png', '.bmp'}; % Add more if needed
    if ~any(strcmpi(ext, supportedFormats))
        errordlg('Unsupported file format. Please select a JPEG or PNG image.', 'Unsupported Format');
        return;
    end
    
    try
        cla(app.UIAxes_28);
        im = imread(fullname);
         imshow(im,'Parent', app.UIAxes_27);
         set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);
          cla(app.UIAxes_28);
         
        
    catch ME
        errordlg(['Error reading the image file: ' ME.message], 'Image Read Error');
    end
        end

        % Button pushed function: DrawingHistogramButton
        function DrawingHistogramButtonPushed(app, event)
           
             fullname = app.EditField_3.Value;
             app.EditField_4.Visible='off';
           app.UploadRefPhotoButton.Visible='off';
           app.OffsetPanel.Visible='off';
           app.New_RangePanel.Visible='off';
           app.GammaPanel.Visible='off';
           app.HistogramMatchingPanel.Visible='off';
           app.ApplyButton_5.Visible='off';
           app.ApplyButton_6.Visible='off';
           app.ApplyButton_7.Visible='off';
           app.HistogramPanel.Visible='on';

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             inputImage=imread(fullname);
         [arr,arg,arb] = drawHistogram( inputImage);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_28);
    
    hold (app.UIAxes_28,'on');
    bar(app.UIAxes_28,arr,'FaceColor','r','EdgeColor','r');
    bar(app.UIAxes_28,arg,'FaceColor','g','EdgeColor','g');
    bar(app.UIAxes_28,arb,'FaceColor','b','EdgeColor','b');
    hold (app.UIAxes_28,'off');

   
    % Clear the edit field


        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            cla(app.UIAxes_28);
            fullname = app.EditField_3.Value;
            app.EditField_4.Visible='off';
           app.UploadRefPhotoButton.Visible='off';
           app.OffsetPanel.Visible='off';
           app.New_RangePanel.Visible='off';
           app.GammaPanel.Visible='off';
           app.HistogramMatchingPanel.Visible='off';
           app.ApplyButton_5.Visible='off';
           app.ApplyButton_6.Visible='off';
           app.ApplyButton_7.Visible='off';
           app.HistogramPanel.Visible='on';

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             inputImage=imread(fullname);
        outputImage = histogramEqualization(inputImage);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_28);
    

    % Display the resized image in UIAxes_2
   %figure
   %subplot(1,2,1)
   %imshow(inputImage) ,title("Original Image");
   %subplot(1,2,2)
   %(outputImage),title("Equalized Image");
    imshow(outputImage, 'Parent', app.UIAxes_28);
   
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_28.DataAspectRatio = [1 1 1];
            

        end

        % Button pushed function: UploadRefPhotoButton
        function UploadRefPhotoButtonPushed(app, event)
            
            [filename, pathname] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an image file');
    if isequal(filename, 0) || isequal(pathname, 0)
        % User canceled the file selection
        return;
    end
    
     fullname = fullfile(pathname, filename);
    app.EditField_4.Value = fullname; % Update EditField with the file path
    
    [~, ~, ext] = fileparts(fullname); % Extract file extension
    supportedFormats = {'.jpg', '.jpeg', '.png', '.bmp'}; % Add more if needed
    if ~any(strcmpi(ext, supportedFormats))
        errordlg('Unsupported file format. Please select a JPEG or PNG image.', 'Unsupported Format');
        return;
    end
    
    try
        im = imread(fullname);
        set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);


    catch ME
        errordlg(['Error reading the image file: ' ME.message], 'Image Read Error');
    end
        end

        % Button pushed function: HistogramMatchingButton
        function HistogramMatchingButtonPushed(app, event)
            
           fullnameOriginal = app.EditField_3.Value;
           fullnameRef = app.EditField_4.Value;
           app.OffsetPanel.Visible='off';
           app.New_RangePanel.Visible='off';
           app.GammaPanel.Visible='off';
           app.OffsetPanel.Visible='off';
           app.New_RangePanel.Visible='off';
           app.GammaPanel.Visible='off';
           app.EditField_4.Visible='on';
           app.UploadRefPhotoButton.Visible='on';
            app.ApplyButton_4.Visible='on';
            app.HistogramMatchingPanel.Visible='on';
           app.HistogramPanel.Visible='off';
           app.ApplyButton_5.Visible='off';
           app.ApplyButton_6.Visible='off';
           app.ApplyButton_7.Visible='off';
           

        % Handle error if edit field is empty
        
       
    % Clear the axes before displaying the new image
    

    % Display the resized image in UIAxes_2
   
    %figure;
    %subplot(1,2,1);
    %imshow(gray_img),title("Original Photo");
    %subplot(1,2,1)
    %imshow(transformedImage),title("Transformed Photo");
    % imshow(transformedImage, 'Parent', app.UIAxes_28);27
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image

        end

        % Button pushed function: ContrastLow_or_HighButton
        function ContrastLow_or_HighButtonPushed(app, event)
            cla(app.UIAxes_28);
            app.EditField_4.Visible='off';
           app.UploadRefPhotoButton.Visible='off';
           app.OffsetPanel.Visible='off';
           app.GammaPanel.Visible='off';
           app.HistogramMatchingPanel.Visible='off';
           app.ApplyButton_6.Visible='off';
           app.ApplyButton_7.Visible='off';
           app.ApplyButton_5.Visible='on';
           app.HistogramPanel.Visible='on';
           app.New_RangePanel.Visible='on';
           
           
        end

        % Callback function
        function HighContrastenhancementButtonPushed(app, event)
            app.EditField_4.Visible='off';
           app.UploadRefPhotoButton.Visible='off';
           app.OffsetPanel.Visible='off';
        end

        % Button pushed function: PowerLawTransformationsButton
        function PowerLawTransformationsButtonPushed(app, event)
             cla(app.UIAxes_28);
            app.EditField_4.Visible='off';
           app.UploadRefPhotoButton.Visible='off';
           app.OffsetPanel.Visible='off';
           app.New_RangePanel.Visible='off';
           app.HistogramMatchingPanel.Visible='off';
           app.ApplyButton_5.Visible='off';
           app.ApplyButton_7.Visible='off';
           app.ApplyButton_6.Visible='on';
           app.HistogramPanel.Visible='on';
           app.GammaPanel.Visible='on';
           app.ApplyButton_6.Visible='on';


        end

        % Button pushed function: BrightnessadjustmentButton
        function BrightnessadjustmentButtonPushed(app, event)
            cla(app.UIAxes_28);
            app.EditField_4.Visible='off';
           app.UploadRefPhotoButton.Visible='off';
           app.New_RangePanel.Visible='off';
           app.GammaPanel.Visible='off';
           app.HistogramMatchingPanel.Visible='off';
           app.ApplyButton_5.Visible='off';
           app.ApplyButton_6.Visible='off';
           app.HistogramPanel.Visible='on';
           app.OffsetPanel.Visible='on';
           app.ApplyButton_7.Visible='on';
           
           
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
             app.ConversionWorkSpacePanel.Visible='off';
             app.MappingWorkSpacePanel.Visible='off';
             app.ImageArithmeticWorkSpacePanel.Visible='off';
             app.SmoothingWorkSpacePanel.Visible='off';
             app.EdgeDetectionWorkSpacePanel.Visible='off';
             app.SharpeningWorkSpacePanel.Visible='off';
             app.UnsharpenMaskingWorkSpacePanel.Visible='off';
            app.HistogramWorkSpacePanel.Visible='on';
            
        end

        % Button pushed function: ArithmeticButton
        function ArithmeticButtonPushed(app, event)
             app.ConversionWorkSpacePanel.Visible='off';
            app.HistogramWorkSpacePanel.Visible='off';
            app.MappingWorkSpacePanel.Visible='off';
            app.SmoothingWorkSpacePanel.Visible='off';
            app.EdgeDetectionWorkSpacePanel.Visible='off';
            app.SharpeningWorkSpacePanel.Visible='off';
            app.UnsharpenMaskingWorkSpacePanel.Visible='off';
            app.ImageArithmeticWorkSpacePanel.Visible='on';
        end

        % Button pushed function: SmoothingButton
        function SmoothingButtonPushed(app, event)
             app.ConversionWorkSpacePanel.Visible='off';
            app.HistogramWorkSpacePanel.Visible='off';
            app.MappingWorkSpacePanel.Visible='off';
            app.ImageArithmeticWorkSpacePanel.Visible='off';
            app.EdgeDetectionWorkSpacePanel.Visible='off';
            app.SharpeningWorkSpacePanel.Visible='off';
            app.UnsharpenMaskingWorkSpacePanel.Visible='off';
            app.SmoothingWorkSpacePanel.Visible='on';
        end

        % Button pushed function: EdgeDetectionButton
        function EdgeDetectionButtonPushed(app, event)
             app.ConversionWorkSpacePanel.Visible='off';
            app.HistogramWorkSpacePanel.Visible='off';
            app.MappingWorkSpacePanel.Visible='off';
            app.ImageArithmeticWorkSpacePanel.Visible='off';
            app.SmoothingWorkSpacePanel.Visible='off';
            app.SharpeningWorkSpacePanel.Visible='off';
            app.UnsharpenMaskingWorkSpacePanel.Visible='off';
            app.EdgeDetectionWorkSpacePanel.Visible='on';
        end

        % Button pushed function: SharpeningButton
        function SharpeningButtonPushed(app, event)
             app.ConversionWorkSpacePanel.Visible='off';
            app.HistogramWorkSpacePanel.Visible='off';
            app.MappingWorkSpacePanel.Visible='off';
            app.ImageArithmeticWorkSpacePanel.Visible='off';
            app.EdgeDetectionWorkSpacePanel.Visible='off';
            app.SmoothingWorkSpacePanel.Visible='off';
            app.EdgeDetectionWorkSpacePanel.Visible='off';
            app.UnsharpenMaskingWorkSpacePanel.Visible='off';
            app.SharpeningWorkSpacePanel.Visible='on';
        end

        % Button pushed function: UploadPhotoButton_4
        function UploadPhotoButton_4Pushed(app, event)
     [filename, pathname] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an image file');
    if isequal(filename, 0) || isequal(pathname, 0)
        % User canceled the file selection
        return;
    end
    
     fullname = fullfile(pathname, filename);
    app.EditField_5.Value = fullname; % Update EditField with the file path
    
    [~, ~, ext] = fileparts(fullname); % Extract file extension
    supportedFormats = {'.jpg', '.jpeg', '.png', '.bmp'}; % Add more if needed
    if ~any(strcmpi(ext, supportedFormats))
        errordlg('Unsupported file format. Please select a JPEG or PNG image.', 'Unsupported Format');
        return;
    end
    
    try
        im = imread(fullname);
        imshow(im,'Parent', app.UIAxes_39);
        set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);
        imshow(im,'Parent', app.UIAxes_40);
        set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);
        % Display the image in UIAxes


    catch ME
        errordlg(['Error reading the image file: ' ME.message], 'Image Read Error');
    end
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            
        end

        % Button pushed function: UploadPhoto_2Button
        function UploadPhoto_2ButtonPushed(app, event)
              [filename, pathname] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an image file');
    if isequal(filename, 0) || isequal(pathname, 0)
        % User canceled the file selection
        return;
    end
    
     fullname = fullfile(pathname, filename);
    app.EditField_7.Value = fullname; % Update EditField with the file path
    
    [~, ~, ext] = fileparts(fullname); % Extract file extension
    supportedFormats = {'.jpg', '.jpeg', '.png', '.bmp'}; % Add more if needed
    if ~any(strcmpi(ext, supportedFormats))
        errordlg('Unsupported file format. Please select a JPEG or PNG image.', 'Unsupported Format');
        return;
    end
    
    try
        im = imread(fullname);
        imshow(im,'Parent', app.UIAxes_38); % Display the image in UIAxes
        imshow(im,'Parent', app.UIAxes_40);
        set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);
         
        
    catch ME
        errordlg(['Error reading the image file: ' ME.message], 'Image Read Error');
    end
        end

        % Button pushed function: AdditionButton
        function AdditionButtonPushed(app, event)
             cla(app.UIAxes_37);
            app.BitsPanel.Visible='off';
            app.ImageArithmeticPanel.Visible='off';
            app.ApplyButton_9.Visible='off';
            app.ApplyButton_10.Visible='off';
            app.ImageAdditionSubtractionPanel.Visible='on';
            app.ApplyButton_8.Visible='on';
           
            % Adjust the size of the axes to match the size of the image

        end

        % Button pushed function: SubtractionButton
        function SubtractionButtonPushed(app, event)
             cla(app.UIAxes_37);
            app.BitsPanel.Visible='off';
            app.ImageArithmeticPanel.Visible='off';
            app.ApplyButton_8.Visible='off';
            app.ApplyButton_10.Visible='off';
              app.ImageAdditionSubtractionPanel.Visible='on';
              app.ApplyButton_9.Visible='on';
          
            
            % Adjust the size of the axes to match the size of the image

        end

        % Button pushed function: ImageNegativeButton
        function ImageNegativeButtonPushed(app, event)
            app.BitsPanel.Visible='off';
            app.ImageAdditionSubtractionPanel.Visible='off';
            app.ApplyButton_8.Visible='off';
            app.ApplyButton_9.Visible='off';
            app.ApplyButton_10.Visible='off';
            app.ImageArithmeticPanel.Visible='on';
            
            fullname = app.EditField_5.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             inputImage=imread(fullname);
        outputImage = imageNegative(inputImage);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_41);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_41);
    imshow(inputImage, 'Parent', app.UIAxes_40);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_41.DataAspectRatio = [1 1 1];
        end

        % Button pushed function: QuantizationButton
        function QuantizationButtonPushed(app, event)
             cla(app.UIAxes_41);
            app.ImageAdditionSubtractionPanel.Visible='off';
            app.ApplyButton_8.Visible='off';
            app.ApplyButton_9.Visible='off';
            app.BitsPanel.Visible='on';
            app.ImageArithmeticPanel.Visible='on';
            app.ApplyButton_10.Visible='on';
            
             
        end

        % Button pushed function: UploadPhotoButton_5
        function UploadPhotoButton_5Pushed(app, event)
              [filename, pathname] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an image file');
    if isequal(filename, 0) || isequal(pathname, 0)
        % User canceled the file selection
        return;
    end
    
     fullname = fullfile(pathname, filename);
    app.EditField_9.Value = fullname; % Update EditField with the file path
    
    [~, ~, ext] = fileparts(fullname); % Extract file extension
    supportedFormats = {'.jpg', '.jpeg', '.png', '.bmp'}; % Add more if needed
    if ~any(strcmpi(ext, supportedFormats))
        errordlg('Unsupported file format. Please select a JPEG or PNG image.', 'Unsupported Format');
        return;
    end
    
    try
        im = imread(fullname);
        imshow(im,'Parent', app.UIAxes_53); % Display the image in UIAxes
        set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);


    catch ME
        errordlg(['Error reading the image file: ' ME.message], 'Image Read Error');
    end
        end

        % Value changed function: MaskSizeEditField
        function MaskSizeEditFieldValueChanged(app, event)
            value = app.MaskSizeEditField.Value;
            
        if mod(value, 2) == 0
         errordlg('Please Enter ODD number.', 'Error');
        return;
        end


        end

        % Button pushed function: MeanFilterButton
        function MeanFilterButtonPushed(app, event)
            cla(app.UIAxes_54);
            app.SigmaPanel.Visible='off';
            app.ApplyButton_12.Visible='off';
            app.ApplyButton_13.Visible='off';
            app.ApplyButton_14.Visible='off';
            app.ApplyButton_15.Visible='off';
            app.ApplyButton_11.Visible='on';
           app.MaskPanel.Visible='on';
           app.MaskSizeEditField.Value=1;
          
            
        end

        % Button pushed function: WeightedFilterButton
        function WeightedFilterButtonPushed(app, event)
             cla(app.UIAxes_54);
            app.MaskPanel.Visible='off';
            app.ApplyButton_11.Visible='off';
            app.ApplyButton_13.Visible='off';
            app.ApplyButton_14.Visible='off';
            app.ApplyButton_15.Visible='off';
            app.SigmaPanel.Visible='on';
            app.ApplyButton_12.Visible='on';
            app.SigmaEditField.Value=1;
           
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            cla(app.UIAxes_54);
            app.SigmaPanel.Visible='off';
            app.ApplyButton_11.Visible='off';
            app.ApplyButton_12.Visible='off';
            app.ApplyButton_14.Visible='off';
            app.ApplyButton_15.Visible='off';
            app.MaskPanel.Visible='on';
             app.ApplyButton_13.Visible='on';
            app.MaskSizeEditField.Value=1;
            
            fullname = app.EditField_9.Value;
            maskSize= app.MaskSizeEditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             inputImage=imread(fullname);
        outputImage = MedianFilter(inputImage,maskSize);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_54);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_54);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_54.DataAspectRatio = [1 1 1];
            
        end

        % Button pushed function: MaxFilterButton
        function MaxFilterButtonPushed(app, event)
            cla(app.UIAxes_54);
            app.SigmaPanel.Visible='off';
             app.ApplyButton_11.Visible='off';
             app.ApplyButton_12.Visible='off';
              app.ApplyButton_13.Visible='off';
              app.ApplyButton_15.Visible='off';
              app.ApplyButton_14.Visible='on';
            app.MaskPanel.Visible='on';
            app.MaskSizeEditField.Value=1;
            
            
        end

        % Button pushed function: MinFilterButton
        function MinFilterButtonPushed(app, event)
           cla(app.UIAxes_54);
            app.MaskSizeEditField.Value=1;
            app.SigmaPanel.Visible='off';
              app.ApplyButton_11.Visible='off';
              app.ApplyButton_12.Visible='off';
               app.ApplyButton_13.Visible='off';
               app.ApplyButton_14.Visible='off';
            app.MaskPanel.Visible='on';
            app.ApplyButton_15.Visible='on';
           
        end

        % Button pushed function: UploadPhotoButton_6
        function UploadPhotoButton_6Pushed(app, event)
             [filename, pathname] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an image file');
    if isequal(filename, 0) || isequal(pathname, 0)
        % User canceled the file selection
        return;
    end
    
     fullname = fullfile(pathname, filename);
    app.EditField_12.Value = fullname; % Update EditField with the file path
    
    [~, ~, ext] = fileparts(fullname); % Extract file extension
    supportedFormats = {'.jpg', '.jpeg', '.png', '.bmp'}; % Add more if needed
    if ~any(strcmpi(ext, supportedFormats))
        errordlg('Unsupported file format. Please select a JPEG or PNG image.', 'Unsupported Format');
        return;
    end
    
    try
        im = imread(fullname);
        imshow(im,'Parent', app.UIAxes_66); % Display the image in UIAxes
        set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);


    catch ME
        errordlg(['Error reading the image file: ' ME.message], 'Image Read Error');
    end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
             fullname = app.EditField_12.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
         inputImage=imread(fullname);
        outputImage = EdgeDetectionFilter(inputImage);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_67);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_67);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_67.DataAspectRatio = [1 1 1];
            
            % Adjust the size of the axes to match the size of the image

        end

        % Button pushed function: UnsharpenButton
        function UnsharpenButtonPushed(app, event)
            app.ConversionWorkSpacePanel.Visible='off';
            app.HistogramWorkSpacePanel.Visible='off';
            app.MappingWorkSpacePanel.Visible='off';
            app.ImageArithmeticWorkSpacePanel.Visible='off';
            app.EdgeDetectionWorkSpacePanel.Visible='off';
            app.SmoothingWorkSpacePanel.Visible='off';
            app.EdgeDetectionWorkSpacePanel.Visible='off';
            app.SharpeningWorkSpacePanel.Visible='off';
            app.UnsharpenMaskingWorkSpacePanel.Visible='on';
        end

        % Button pushed function: UploadPhotoButton_7
        function UploadPhotoButton_7Pushed(app, event)
              [filename, pathname] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an image file');
    if isequal(filename, 0) || isequal(pathname, 0)
        % User canceled the file selection
        return;
    end
    
     fullname = fullfile(pathname, filename);
    app.EditField_15.Value = fullname; % Update EditField with the file path
    
    [~, ~, ext] = fileparts(fullname); % Extract file extension
    supportedFormats = {'.jpg', '.jpeg', '.png', '.bmp'}; % Add more if needed
    if ~any(strcmpi(ext, supportedFormats))
        errordlg('Unsupported file format. Please select a JPEG or PNG image.', 'Unsupported Format');
        return;
    end
    
    try
        im = imread(fullname);
        imshow(im,'Parent', app.UIAxes_79); % Display the image in UIAxes
        set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);


    catch ME
        errordlg(['Error reading the image file: ' ME.message], 'Image Read Error');
    end
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
               fullname = app.EditField_15.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
         inputImage=imread(fullname);
         set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);
        outputImage = SharpeningFilter(inputImage);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_80);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_80);
    
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_80.DataAspectRatio = [1 1 1];
            
        end

        % Button pushed function: UploadPhotoButton_8
        function UploadPhotoButton_8Pushed(app, event)
                    [filename, pathname] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an image file');
    if isequal(filename, 0) || isequal(pathname, 0)
        % User canceled the file selection
        return;
    end
    
     fullname = fullfile(pathname, filename);
    app.EditField_18.Value = fullname; % Update EditField with the file path
    
    [~, ~, ext] = fileparts(fullname); % Extract file extension
    supportedFormats = {'.jpg', '.jpeg', '.png', '.bmp'}; % Add more if needed
    if ~any(strcmpi(ext, supportedFormats))
        errordlg('Unsupported file format. Please select a JPEG or PNG image.', 'Unsupported Format');
        return;
    end
    
    try
        im = imread(fullname);
        imshow(im,'Parent', app.UIAxes_92); % Display the image in UIAxes
        set(app.ImageProcessingAppUIFigure, 'Visible', 'on');
        
        % Bring the figure to front
        figure(app.ImageProcessingAppUIFigure);
         
        
    catch ME
        errordlg(['Error reading the image file: ' ME.message], 'Image Read Error');
    end
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
               fullname = app.EditField_18.Value;
               sigma=app.SigmaEditField_5.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
         inputImage=imread(fullname);
        outputImage = UnsharpMaskFilter(inputImage,sigma);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_93);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_93);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_93.DataAspectRatio = [1 1 1];
            
        end

        % Button pushed function: ApplyButton_4
        function ApplyButton_4Pushed(app, event)
           
            fullname1 = app.EditField_3.Value;
            fullname2 = app.EditField_4.Value;

        % Handle error if edit field is empty
         if isempty(fullname1)
        errordlg('Please select an image file.', 'Error');
        return;
         end
          if isempty(fullname2)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             img=imread(fullname1);
             ref_img=imread(fullname2);
        [transformed_img, trans_histogram ,ref_gray_img,ref_histogram, gray_img,histogram] = histogramMatching(img, ref_img)
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

     cla(app.UIAxes_94);
     cla(app.UIAxes_95);
     cla(app.UIAxes_96);
     cla(app.UIAxes_97);
     cla(app.UIAxes_98);
     cla(app.UIAxes_99);

    % Display the resized image in UIAxes_2
    imshow(gray_img, 'Parent', app.UIAxes_98);
     hold (app.UIAxes_97,'on');
    bar(app.UIAxes_97,histogram,'FaceColor','r','EdgeColor','r')
    hold (app.UIAxes_97,'off');
    
     imshow(ref_gray_img, 'Parent', app.UIAxes_96);
     hold (app.UIAxes_94,'on');
    bar(app.UIAxes_94,ref_histogram,'FaceColor','r','EdgeColor','r')
    hold (app.UIAxes_94,'off');
    
     imshow(transformed_img, 'Parent', app.UIAxes_99);
     hold (app.UIAxes_95,'on');
    bar(app.UIAxes_95,trans_histogram,'FaceColor','r','EdgeColor','r')
    hold (app.UIAxes_95,'off');


    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
   
        end

        % Button pushed function: ApplyButton_5
        function ApplyButton_5Pushed(app, event)
            fullname = app.EditField_3.Value;
           newMax=app.NEW_MAXEditField.Value;
           newMin=app.NEW_MINEditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
          fullname = app.EditField_3.Value;
         inputImage=imread(fullname);
        outputImage = Contrast(newMax,newMin,inputImage);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

         % Clear the axes before displaying the new image
         cla(app.UIAxes_28);

        % Display the resized image in UIAxes_2
         imshow(outputImage, 'Parent', app.UIAxes_28);
     
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_28.DataAspectRatio = [1 1 1];
            
  
        end

        % Button pushed function: ApplyButton_6
        function ApplyButton_6Pushed(app, event)
              fullname = app.EditField_3.Value;
           gamma=app.GammaEditField.Value;
           

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
           fullname = app.EditField_3.Value;
          gamma=app.GammaEditField.Value;
         inputImage=imread(fullname);
        outputImage = powerLaw(inputImage,gamma);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_28);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_28);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_28.DataAspectRatio = [1 1 1];
        end

        % Button pushed function: ApplyButton_7
        function ApplyButton_7Pushed(app, event)
            fullname = app.EditField_3.Value;
           offset=app.OffsetEditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
            fullname = app.EditField_3.Value;
          offset=app.OffsetEditField.Value;
         inputImage=imread(fullname);
        outputImage = Brightness(inputImage,offset);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_28);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_28);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_28.DataAspectRatio = [1 1 1];
            
  
        end

        % Button pushed function: ApplyButton_8
        function ApplyButton_8Pushed(app, event)
             fullname1 = app.EditField_5.Value;
            fullname2 = app.EditField_7.Value;
            

        % Handle error if edit field is empty
         if isempty(fullname1 )
        errordlg('Please select an image file.', 'Error');
        return;
         end
          if isempty(fullname2 )
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             firstImage=imread(fullname1);
              secondImage=imread(fullname2);
        outputImage = Addition(firstImage,secondImage);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_37);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_37);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_37.DataAspectRatio = [1 1 1];
            
        end

        % Button pushed function: ApplyButton_9
        function ApplyButton_9Pushed(app, event)
              fullname1 = app.EditField_5.Value;
            fullname2 = app.EditField_7.Value;
            

        % Handle error if edit field is empty
         if isempty(fullname1 )
        errordlg('Please select an image file.', 'Error');
        return;
         end
          if isempty(fullname2 )
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             firstImage=imread(fullname1);
              secondImage=imread(fullname2);
        outputImage = Subtraction(firstImage,secondImage);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_37);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_37);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_37.DataAspectRatio = [1 1 1];
        end

        % Button pushed function: ApplyButton_10
        function ApplyButton_10Pushed(app, event)
            fullname = app.EditField_5.Value;
             k=app.NoOfBitsEditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             inputImage=imread(fullname);
        outputImage = Quantization(inputImage,k);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_41);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_41);
    imshow(inputImage, 'Parent', app.UIAxes_40);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_41.DataAspectRatio = [1 1 1];
        end

        % Button pushed function: ApplyButton_11
        function ApplyButton_11Pushed(app, event)
              fullname = app.EditField_9.Value;
            maskSize= app.MaskSizeEditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             inputImage=imread(fullname);
        outputImage = MeanFilter(inputImage,maskSize);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_54);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_54);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_54.DataAspectRatio = [1 1 1];
        end

        % Button pushed function: ApplyButton_12
        function ApplyButton_12Pushed(app, event)
              fullname = app.EditField_9.Value;
            sigma= app.SigmaEditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             inputImage=imread(fullname);
        outputImage = WeightedFilter(inputImage,sigma);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_54);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_54);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_54.DataAspectRatio = [1 1 1];
        end

        % Button pushed function: ApplyButton_13
        function ApplyButton_13Pushed(app, event)
            fullname = app.EditField_9.Value;
            maskSize= app.MaskSizeEditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             inputImage=imread(fullname);
        outputImage = MedianFilter(inputImage,maskSize);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_54);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_54);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_54.DataAspectRatio = [1 1 1];
        end

        % Button pushed function: ApplyButton_14
        function ApplyButton_14Pushed(app, event)
            fullname = app.EditField_9.Value;
            maskSize= app.MaskSizeEditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
          inputImage=imread(fullname);
        outputImage = MaxFilter(inputImage,maskSize);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_54);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_54);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_54.DataAspectRatio = [1 1 1];
        end

        % Button pushed function: ApplyButton_15
        function ApplyButton_15Pushed(app, event)
             fullname = app.EditField_9.Value;
            maskSize= app.MaskSizeEditField.Value;

        % Handle error if edit field is empty
         if isempty(fullname)
        errordlg('Please select an image file.', 'Error');
        return;
         end

         % Call the DirectMappingZeroOrder function to resize the image
         try
             inputImage=imread(fullname);
        outputImage = MinFilter(inputImage,maskSize);
         catch ME
        errordlg(['Error resizing image: ' ME.message], 'Error');
        return;
         end

    % Clear the axes before displaying the new image
    cla(app.UIAxes_54);

    % Display the resized image in UIAxes_2
    imshow(outputImage, 'Parent', app.UIAxes_54);
    
    % Clear the edit field
    

    % Adjust the size of the axes to match the size of the image
    app.UIAxes_54.DataAspectRatio = [1 1 1];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageProcessingAppUIFigure = uifigure;
            app.ImageProcessingAppUIFigure.Name = 'Image Processing App';
            app.ImageProcessingAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImageProcessingAppUIFigureCloseRequest, true);
            app.ImageProcessingAPPPanel = uipanel(app.ImageProcessingAppUIFigure);
            app.ImageProcessingAPPPanel.Title = 'Image Processing APP';
            app.Panel = uipanel(app.ImageProcessingAPPPanel);
            app.MappingButton = uibutton(app.Panel, 'push');
            app.MappingButton.ButtonPushedFcn = createCallbackFcn(app, @MappingButtonPushed, true);
            app.MappingButton.Icon = 'resize.png';
            app.MappingButton.Text = 'Mapping';
            app.ConversionButton = uibutton(app.Panel, 'push');
            app.ConversionButton.ButtonPushedFcn = createCallbackFcn(app, @ConversionButtonPushed, true);
            app.ConversionButton.Icon = 'photo (1).png';
            app.ConversionButton.Text = 'Conversion';
            app.HistogramButton = uibutton(app.Panel, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Icon = 'histogram (1).png';
            app.HistogramButton.Text = 'Histogram';
            app.ArithmeticButton = uibutton(app.Panel, 'push');
            app.ArithmeticButton.ButtonPushedFcn = createCallbackFcn(app, @ArithmeticButtonPushed, true);
            app.ArithmeticButton.Icon = 'picture.png';
            app.ArithmeticButton.Text = ' Arithmetic';
            app.SmoothingButton = uibutton(app.Panel, 'push');
            app.SmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothingButtonPushed, true);
            app.SmoothingButton.Icon = 'magic-wand.png';
            app.SmoothingButton.Text = 'Smoothing';
            app.EdgeDetectionButton = uibutton(app.Panel, 'push');
            app.EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectionButtonPushed, true);
            app.EdgeDetectionButton.Icon = 'detection.png';
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.SharpeningButton = uibutton(app.Panel, 'push');
            app.SharpeningButton.ButtonPushedFcn = createCallbackFcn(app, @SharpeningButtonPushed, true);
            app.SharpeningButton.Icon = 'magic-wand (1).png';
            app.SharpeningButton.Text = 'Sharpening ';
            app.UnsharpenButton = uibutton(app.Panel, 'push');
            app.UnsharpenButton.ButtonPushedFcn = createCallbackFcn(app, @UnsharpenButtonPushed, true);
            app.UnsharpenButton.Icon = 'photo-editing.png';
            app.UnsharpenButton.Text = 'Unsharpen ';
            app.ConversionWorkSpacePanel = uipanel(app.ImageProcessingAPPPanel);
            app.ConversionWorkSpacePanel.Title = 'Conversion Work Space';
            app.ConversionWorkSpacePanel.Visible = 'off';
            app.UploadPhotoButton_2 = uibutton(app.ConversionWorkSpacePanel, 'push');
            app.UploadPhotoButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadPhotoButton_2Pushed, true);
            app.UploadPhotoButton_2.Icon = 'gallery.png';
            app.UploadPhotoButton_2.Text = 'Upload Photo';
            app.EditField_2 = uieditfield(app.ConversionWorkSpacePanel, 'text');
            app.DirectMappingZeroOrderPanel_2 = uipanel(app.ConversionWorkSpacePanel);
            app.DirectMappingZeroOrderPanel_2.Title = 'Direct Mapping: Zero - Order';
            app.DirectMappingZeroOrderPanel_2.Visible = 'off';
            app.UIAxes_9 = uiaxes(app.DirectMappingZeroOrderPanel_2);
            title(app.UIAxes_9, 'Original Photo')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.XTickLabel = {'[ ]'};
            app.UIAxes_9.YTick = [];
            app.UIAxes_9.YTickLabel = {'[ ]'};
            app.UIAxes_10 = uiaxes(app.DirectMappingZeroOrderPanel_2);
            title(app.UIAxes_10, 'Resized Photo')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.XTickLabel = {'[ ]'};
            app.UIAxes_10.YTick = [];
            app.UIAxes_10.YTickLabel = {'[ ]'};
            app.FactorEditField_3Label = uilabel(app.DirectMappingZeroOrderPanel_2);
            app.FactorEditField_3Label.Text = 'Factor';
            app.FactorEditField_3 = uieditfield(app.DirectMappingZeroOrderPanel_2, 'numeric');
            app.FactorEditField_3.ValueDisplayFormat = '%.0f';
            app.ResizeButton_5 = uibutton(app.DirectMappingZeroOrderPanel_2, 'push');
            app.ResizeButton_5.Text = 'Resize';
            app.DirectMappingOneOrderPanel_2 = uipanel(app.ConversionWorkSpacePanel);
            app.DirectMappingOneOrderPanel_2.Title = 'Direct Mapping: One - Order';
            app.DirectMappingOneOrderPanel_2.Visible = 'off';
            app.UIAxes_11 = uiaxes(app.DirectMappingOneOrderPanel_2);
            title(app.UIAxes_11, 'Original Photo')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.XTickLabel = {'[ ]'};
            app.UIAxes_11.YTick = [];
            app.UIAxes_11.YTickLabel = {'[ ]'};
            app.UIAxes_12 = uiaxes(app.DirectMappingOneOrderPanel_2);
            title(app.UIAxes_12, 'Resized Photo')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.XTickLabel = {'[ ]'};
            app.UIAxes_12.YTick = [];
            app.UIAxes_12.YTickLabel = {'[ ]'};
            app.FactorEditField_4Label = uilabel(app.DirectMappingOneOrderPanel_2);
            app.FactorEditField_4Label.Text = 'Factor';
            app.FactorEditField_4 = uieditfield(app.DirectMappingOneOrderPanel_2, 'numeric');
            app.FactorEditField_4.ValueDisplayFormat = '%.0f';
            app.ResizeButton_6 = uibutton(app.DirectMappingOneOrderPanel_2, 'push');
            app.ResizeButton_6.Text = 'Resize';
            app.ReverseMappingOneOrderPanel_2 = uipanel(app.ConversionWorkSpacePanel);
            app.ReverseMappingOneOrderPanel_2.Title = 'Reverse Mapping: One - Order';
            app.ReverseMappingOneOrderPanel_2.Visible = 'off';
            app.UIAxes_13 = uiaxes(app.ReverseMappingOneOrderPanel_2);
            title(app.UIAxes_13, 'Original Photo')
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.XTickLabel = {'[ ]'};
            app.UIAxes_13.YTick = [];
            app.UIAxes_13.YTickLabel = {'[ ]'};
            app.UIAxes_14 = uiaxes(app.ReverseMappingOneOrderPanel_2);
            title(app.UIAxes_14, 'Resized Photo')
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.XTickLabel = {'[ ]'};
            app.UIAxes_14.YTick = [];
            app.UIAxes_14.YTickLabel = {'[ ]'};
            app.ResizeButton_7 = uibutton(app.ReverseMappingOneOrderPanel_2, 'push');
            app.ResizeButton_7.Text = 'Resize';
            app.RowFactorEditField_3Label = uilabel(app.ReverseMappingOneOrderPanel_2);
            app.RowFactorEditField_3Label.Text = 'Row Factor';
            app.RowFactorEditField_3 = uieditfield(app.ReverseMappingOneOrderPanel_2, 'numeric');
            app.RowFactorEditField_3.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_3Label = uilabel(app.ReverseMappingOneOrderPanel_2);
            app.ColFactorEditField_3Label.Text = 'Col Factor';
            app.ColFactorEditField_3 = uieditfield(app.ReverseMappingOneOrderPanel_2, 'numeric');
            app.ColFactorEditField_3.ValueDisplayFormat = '%.0f';
            app.ReverseMappingZeroOrderPanel_2 = uipanel(app.ConversionWorkSpacePanel);
            app.ReverseMappingZeroOrderPanel_2.Title = 'Reverse Mapping: Zero - Order';
            app.ReverseMappingZeroOrderPanel_2.Visible = 'off';
            app.UIAxes_15 = uiaxes(app.ReverseMappingZeroOrderPanel_2);
            title(app.UIAxes_15, 'Original Photo')
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.XTickLabel = {'[ ]'};
            app.UIAxes_15.YTick = [];
            app.UIAxes_15.YTickLabel = {'[ ]'};
            app.UIAxes_16 = uiaxes(app.ReverseMappingZeroOrderPanel_2);
            title(app.UIAxes_16, 'Resized Photo')
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.XTickLabel = {'[ ]'};
            app.UIAxes_16.YTick = [];
            app.UIAxes_16.YTickLabel = {'[ ]'};
            app.ResizeButton_8 = uibutton(app.ReverseMappingZeroOrderPanel_2, 'push');
            app.ResizeButton_8.Text = 'Resize';
            app.RowFactorEditField_4Label = uilabel(app.ReverseMappingZeroOrderPanel_2);
            app.RowFactorEditField_4Label.Text = 'Row Factor';
            app.RowFactorEditField_4 = uieditfield(app.ReverseMappingZeroOrderPanel_2, 'numeric');
            app.RowFactorEditField_4.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_4Label = uilabel(app.ReverseMappingZeroOrderPanel_2);
            app.ColFactorEditField_4Label.Text = 'Col Factor';
            app.ColFactorEditField_4 = uieditfield(app.ReverseMappingZeroOrderPanel_2, 'numeric');
            app.ColFactorEditField_4.ValueDisplayFormat = '%.0f';
            app.ConverttoGrayScalePanel = uipanel(app.ConversionWorkSpacePanel);
            app.ConverttoGrayScalePanel.Title = 'Convert to GrayScale :';
            app.UIAxes_17 = uiaxes(app.ConverttoGrayScalePanel);
            title(app.UIAxes_17, 'Original Photo')
            app.UIAxes_17.XTick = [];
            app.UIAxes_17.XTickLabel = {'[ ]'};
            app.UIAxes_17.YTick = [];
            app.UIAxes_17.YTickLabel = {'[ ]'};
            app.UIAxes_18 = uiaxes(app.ConverttoGrayScalePanel);
            title(app.UIAxes_18, 'Edited Photo')
            app.UIAxes_18.XTick = [];
            app.UIAxes_18.XTickLabel = {'[ ]'};
            app.UIAxes_18.YTick = [];
            app.UIAxes_18.YTickLabel = {'[ ]'};
            app.DecomposingButton = uibutton(app.ConversionWorkSpacePanel, 'push');
            app.DecomposingButton.ButtonPushedFcn = createCallbackFcn(app, @DecomposingButtonPushed, true);
            app.DecomposingButton.Text = 'Decomposing';
            app.LuminanceButton = uibutton(app.ConversionWorkSpacePanel, 'push');
            app.LuminanceButton.ButtonPushedFcn = createCallbackFcn(app, @LuminanceButtonPushed, true);
            app.LuminanceButton.Text = 'Luminance';
            app.AveragingButton = uibutton(app.ConversionWorkSpacePanel, 'push');
            app.AveragingButton.ButtonPushedFcn = createCallbackFcn(app, @AveragingButtonPushed, true);
            app.AveragingButton.Text = 'Averaging';
            app.SingleChannelButton = uibutton(app.ConversionWorkSpacePanel, 'push');
            app.SingleChannelButton.ButtonPushedFcn = createCallbackFcn(app, @SingleChannelButtonPushed, true);
            app.SingleChannelButton.Text = 'Single Channel';
            app.DesaturationButton = uibutton(app.ConversionWorkSpacePanel, 'push');
            app.DesaturationButton.ButtonPushedFcn = createCallbackFcn(app, @DesaturationButtonPushed, true);
            app.DesaturationButton.Text = 'Desaturation';
            app.EdgeDetectionWorkSpacePanel = uipanel(app.ImageProcessingAPPPanel);
            app.EdgeDetectionWorkSpacePanel.Title = 'Edge Detection Work Space';
            app.EdgeDetectionWorkSpacePanel.Visible = 'off';
            app.UploadPhotoButton_6 = uibutton(app.EdgeDetectionWorkSpacePanel, 'push');
            app.UploadPhotoButton_6.ButtonPushedFcn = createCallbackFcn(app, @UploadPhotoButton_6Pushed, true);
            app.UploadPhotoButton_6.Icon = 'gallery.png';
            app.UploadPhotoButton_6.Text = 'Upload Photo';
            app.EditField_12 = uieditfield(app.EdgeDetectionWorkSpacePanel, 'text');
            app.histogramFunctionsPanel_6 = uipanel(app.EdgeDetectionWorkSpacePanel);
            app.histogramFunctionsPanel_6.Title = 'Direct Mapping: Zero - Order';
            app.histogramFunctionsPanel_6.Visible = 'off';
            app.UIAxes_55 = uiaxes(app.histogramFunctionsPanel_6);
            title(app.UIAxes_55, 'Original Photo')
            app.UIAxes_55.XTick = [];
            app.UIAxes_55.XTickLabel = {'[ ]'};
            app.UIAxes_55.YTick = [];
            app.UIAxes_55.YTickLabel = {'[ ]'};
            app.UIAxes_56 = uiaxes(app.histogramFunctionsPanel_6);
            title(app.UIAxes_56, 'Resized Photo')
            app.UIAxes_56.XTick = [];
            app.UIAxes_56.XTickLabel = {'[ ]'};
            app.UIAxes_56.YTick = [];
            app.UIAxes_56.YTickLabel = {'[ ]'};
            app.FactorEditField_11Label = uilabel(app.histogramFunctionsPanel_6);
            app.FactorEditField_11Label.Text = 'Factor';
            app.FactorEditField_11 = uieditfield(app.histogramFunctionsPanel_6, 'numeric');
            app.FactorEditField_11.ValueDisplayFormat = '%.0f';
            app.ResizeButton_21 = uibutton(app.histogramFunctionsPanel_6, 'push');
            app.ResizeButton_21.Text = 'Resize';
            app.DirectMappingOneOrderPanel_6 = uipanel(app.EdgeDetectionWorkSpacePanel);
            app.DirectMappingOneOrderPanel_6.Title = 'Direct Mapping: One - Order';
            app.DirectMappingOneOrderPanel_6.Visible = 'off';
            app.UIAxes_57 = uiaxes(app.DirectMappingOneOrderPanel_6);
            title(app.UIAxes_57, 'Original Photo')
            app.UIAxes_57.XTick = [];
            app.UIAxes_57.XTickLabel = {'[ ]'};
            app.UIAxes_57.YTick = [];
            app.UIAxes_57.YTickLabel = {'[ ]'};
            app.UIAxes_58 = uiaxes(app.DirectMappingOneOrderPanel_6);
            title(app.UIAxes_58, 'Resized Photo')
            app.UIAxes_58.XTick = [];
            app.UIAxes_58.XTickLabel = {'[ ]'};
            app.UIAxes_58.YTick = [];
            app.UIAxes_58.YTickLabel = {'[ ]'};
            app.FactorEditField_12Label = uilabel(app.DirectMappingOneOrderPanel_6);
            app.FactorEditField_12Label.Text = 'Factor';
            app.FactorEditField_12 = uieditfield(app.DirectMappingOneOrderPanel_6, 'numeric');
            app.FactorEditField_12.ValueDisplayFormat = '%.0f';
            app.ResizeButton_22 = uibutton(app.DirectMappingOneOrderPanel_6, 'push');
            app.ResizeButton_22.Text = 'Resize';
            app.ReverseMappingOneOrderPanel_6 = uipanel(app.EdgeDetectionWorkSpacePanel);
            app.ReverseMappingOneOrderPanel_6.Title = 'Reverse Mapping: One - Order';
            app.ReverseMappingOneOrderPanel_6.Visible = 'off';
            app.UIAxes_59 = uiaxes(app.ReverseMappingOneOrderPanel_6);
            title(app.UIAxes_59, 'Original Photo')
            app.UIAxes_59.XTick = [];
            app.UIAxes_59.XTickLabel = {'[ ]'};
            app.UIAxes_59.YTick = [];
            app.UIAxes_59.YTickLabel = {'[ ]'};
            app.UIAxes_60 = uiaxes(app.ReverseMappingOneOrderPanel_6);
            title(app.UIAxes_60, 'Resized Photo')
            app.UIAxes_60.XTick = [];
            app.UIAxes_60.XTickLabel = {'[ ]'};
            app.UIAxes_60.YTick = [];
            app.UIAxes_60.YTickLabel = {'[ ]'};
            app.ResizeButton_23 = uibutton(app.ReverseMappingOneOrderPanel_6, 'push');
            app.ResizeButton_23.Text = 'Resize';
            app.RowFactorEditField_11Label = uilabel(app.ReverseMappingOneOrderPanel_6);
            app.RowFactorEditField_11Label.Text = 'Row Factor';
            app.RowFactorEditField_11 = uieditfield(app.ReverseMappingOneOrderPanel_6, 'numeric');
            app.RowFactorEditField_11.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_11Label = uilabel(app.ReverseMappingOneOrderPanel_6);
            app.ColFactorEditField_11Label.Text = 'Col Factor';
            app.ColFactorEditField_11 = uieditfield(app.ReverseMappingOneOrderPanel_6, 'numeric');
            app.ColFactorEditField_11.ValueDisplayFormat = '%.0f';
            app.ReverseMappingZeroOrderPanel_6 = uipanel(app.EdgeDetectionWorkSpacePanel);
            app.ReverseMappingZeroOrderPanel_6.Title = 'Reverse Mapping: Zero - Order';
            app.ReverseMappingZeroOrderPanel_6.Visible = 'off';
            app.UIAxes_61 = uiaxes(app.ReverseMappingZeroOrderPanel_6);
            title(app.UIAxes_61, 'Original Photo')
            app.UIAxes_61.XTick = [];
            app.UIAxes_61.XTickLabel = {'[ ]'};
            app.UIAxes_61.YTick = [];
            app.UIAxes_61.YTickLabel = {'[ ]'};
            app.UIAxes_62 = uiaxes(app.ReverseMappingZeroOrderPanel_6);
            title(app.UIAxes_62, 'Resized Photo')
            app.UIAxes_62.XTick = [];
            app.UIAxes_62.XTickLabel = {'[ ]'};
            app.UIAxes_62.YTick = [];
            app.UIAxes_62.YTickLabel = {'[ ]'};
            app.ResizeButton_24 = uibutton(app.ReverseMappingZeroOrderPanel_6, 'push');
            app.ResizeButton_24.Text = 'Resize';
            app.RowFactorEditField_12Label = uilabel(app.ReverseMappingZeroOrderPanel_6);
            app.RowFactorEditField_12Label.Text = 'Row Factor';
            app.RowFactorEditField_12 = uieditfield(app.ReverseMappingZeroOrderPanel_6, 'numeric');
            app.RowFactorEditField_12.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_12Label = uilabel(app.ReverseMappingZeroOrderPanel_6);
            app.ColFactorEditField_12Label.Text = 'Col Factor';
            app.ColFactorEditField_12 = uieditfield(app.ReverseMappingZeroOrderPanel_6, 'numeric');
            app.ColFactorEditField_12.ValueDisplayFormat = '%.0f';
            app.ImageAdditionSubtractionPanel_3 = uipanel(app.EdgeDetectionWorkSpacePanel);
            app.ImageAdditionSubtractionPanel_3.Title = 'Image Addition / Subtraction:';
            app.ImageAdditionSubtractionPanel_3.Visible = 'off';
            app.UIAxes_63 = uiaxes(app.ImageAdditionSubtractionPanel_3);
            title(app.UIAxes_63, ' Result')
            app.UIAxes_63.XTick = [];
            app.UIAxes_63.XTickLabel = {'[ ]'};
            app.UIAxes_63.YTick = [];
            app.UIAxes_63.YTickLabel = {'[ ]'};
            app.UIAxes_64 = uiaxes(app.ImageAdditionSubtractionPanel_3);
            title(app.UIAxes_64, 'Second Image')
            app.UIAxes_64.XTick = [];
            app.UIAxes_64.XTickLabel = {'[ ]'};
            app.UIAxes_64.YTick = [];
            app.UIAxes_64.YTickLabel = {'[ ]'};
            app.OffsetPanel_6 = uipanel(app.ImageAdditionSubtractionPanel_3);
            app.OffsetPanel_6.Title = 'Offset';
            app.OffsetPanel_6.Visible = 'off';
            app.OffsetEditField_6Label = uilabel(app.OffsetPanel_6);
            app.OffsetEditField_6Label.Text = 'Offset';
            app.OffsetEditField_6 = uieditfield(app.OffsetPanel_6, 'numeric');
            app.New_RangePanel_6 = uipanel(app.ImageAdditionSubtractionPanel_3);
            app.New_RangePanel_6.Title = 'New_Range:';
            app.New_RangePanel_6.Visible = 'off';
            app.NEW_MAXEditField_6Label = uilabel(app.New_RangePanel_6);
            app.NEW_MAXEditField_6Label.Text = 'NEW_MAX';
            app.NEW_MAXEditField_6 = uieditfield(app.New_RangePanel_6, 'numeric');
            app.NEW_MINEditField_6Label = uilabel(app.New_RangePanel_6);
            app.NEW_MINEditField_6Label.Text = 'NEW_MIN';
            app.NEW_MINEditField_6 = uieditfield(app.New_RangePanel_6, 'numeric');
            app.GammaPanel_6 = uipanel(app.ImageAdditionSubtractionPanel_3);
            app.GammaPanel_6.Title = 'Gamma :)';
            app.GammaPanel_6.Visible = 'off';
            app.GammaEditField_6Label = uilabel(app.GammaPanel_6);
            app.GammaEditField_6Label.Text = 'Gamma:';
            app.GammaEditField_6 = uieditfield(app.GammaPanel_6, 'numeric');
            app.EditField_13 = uieditfield(app.ImageAdditionSubtractionPanel_3, 'text');
            app.EditField_13.Visible = 'off';
            app.EditField_14 = uieditfield(app.ImageAdditionSubtractionPanel_3, 'text');
            app.UIAxes_65 = uiaxes(app.ImageAdditionSubtractionPanel_3);
            title(app.UIAxes_65, 'First Image')
            app.UIAxes_65.XTick = [];
            app.UIAxes_65.XTickLabel = {'[ ]'};
            app.UIAxes_65.YTick = [];
            app.UIAxes_65.YTickLabel = {'[ ]'};
            app.BitsPanel_3 = uipanel(app.EdgeDetectionWorkSpacePanel);
            app.BitsPanel_3.Title = 'Bits:';
            app.BitsPanel_3.Visible = 'off';
            app.NoOfBitsEditField_3Label = uilabel(app.BitsPanel_3);
            app.NoOfBitsEditField_3Label.Text = 'No.Of .Bits';
            app.NoOfBitsEditField_3 = uieditfield(app.BitsPanel_3, 'numeric');
            app.NoOfBitsEditField_3.Limits = [0 Inf];
            app.EdgeDetectionPanel = uipanel(app.EdgeDetectionWorkSpacePanel);
            app.EdgeDetectionPanel.Title = 'Edge Detection:';
            app.OffsetPanel_7 = uipanel(app.EdgeDetectionPanel);
            app.OffsetPanel_7.Title = 'Offset';
            app.OffsetPanel_7.Visible = 'off';
            app.OffsetEditField_7Label = uilabel(app.OffsetPanel_7);
            app.OffsetEditField_7Label.Text = 'Offset';
            app.OffsetEditField_7 = uieditfield(app.OffsetPanel_7, 'numeric');
            app.New_RangePanel_7 = uipanel(app.EdgeDetectionPanel);
            app.New_RangePanel_7.Title = 'New_Range:';
            app.New_RangePanel_7.Visible = 'off';
            app.NEW_MAXEditField_7Label = uilabel(app.New_RangePanel_7);
            app.NEW_MAXEditField_7Label.Text = 'NEW_MAX';
            app.NEW_MAXEditField_7 = uieditfield(app.New_RangePanel_7, 'numeric');
            app.NEW_MINEditField_7Label = uilabel(app.New_RangePanel_7);
            app.NEW_MINEditField_7Label.Text = 'NEW_MIN';
            app.NEW_MINEditField_7 = uieditfield(app.New_RangePanel_7, 'numeric');
            app.GammaPanel_7 = uipanel(app.EdgeDetectionPanel);
            app.GammaPanel_7.Title = 'Gamma :)';
            app.GammaPanel_7.Visible = 'off';
            app.GammaEditField_7Label = uilabel(app.GammaPanel_7);
            app.GammaEditField_7Label.Text = 'Gamma:';
            app.GammaEditField_7 = uieditfield(app.GammaPanel_7, 'numeric');
            app.MaskPanel_2 = uipanel(app.EdgeDetectionPanel);
            app.MaskPanel_2.Title = 'Mask:';
            app.MaskPanel_2.Visible = 'off';
            app.MaskSizeEditField_2Label = uilabel(app.MaskPanel_2);
            app.MaskSizeEditField_2Label.Text = 'Mask Size:';
            app.MaskSizeEditField_2 = uieditfield(app.MaskPanel_2, 'numeric');
            app.MaskSizeEditField_2.Limits = [1 Inf];
            app.MaskSizeEditField_2.ValueDisplayFormat = '%.0f';
            app.MaskSizeEditField_2.Value = 1;
            app.SigmaPanel_2 = uipanel(app.EdgeDetectionPanel);
            app.SigmaPanel_2.Title = 'Sigma:';
            app.SigmaPanel_2.Visible = 'off';
            app.SigmaEditField_2Label = uilabel(app.SigmaPanel_2);
            app.SigmaEditField_2Label.Text = 'Sigma σ:';
            app.SigmaEditField_2 = uieditfield(app.SigmaPanel_2, 'numeric');
            app.SigmaEditField_2.Limits = [0 Inf];
            app.UIAxes_66 = uiaxes(app.EdgeDetectionPanel);
            title(app.UIAxes_66, 'Original ')
            app.UIAxes_66.XTick = [];
            app.UIAxes_66.XTickLabel = {'[ ]'};
            app.UIAxes_66.YTick = [];
            app.UIAxes_66.YTickLabel = {'[ ]'};
            app.UIAxes_67 = uiaxes(app.EdgeDetectionPanel);
            title(app.UIAxes_67, 'Result')
            app.UIAxes_67.XTick = [];
            app.UIAxes_67.XTickLabel = {'[ ]'};
            app.UIAxes_67.YTick = [];
            app.UIAxes_67.YTickLabel = {'[ ]'};
            app.ApplyButton = uibutton(app.EdgeDetectionWorkSpacePanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Icon = 'check.png';
            app.ApplyButton.Text = 'Apply';
            app.SharpeningWorkSpacePanel = uipanel(app.ImageProcessingAPPPanel);
            app.SharpeningWorkSpacePanel.Title = 'Sharpening Work Space';
            app.SharpeningWorkSpacePanel.Visible = 'off';
            app.UploadPhotoButton_7 = uibutton(app.SharpeningWorkSpacePanel, 'push');
            app.UploadPhotoButton_7.ButtonPushedFcn = createCallbackFcn(app, @UploadPhotoButton_7Pushed, true);
            app.UploadPhotoButton_7.Icon = 'gallery.png';
            app.UploadPhotoButton_7.Text = 'Upload Photo';
            app.EditField_15 = uieditfield(app.SharpeningWorkSpacePanel, 'text');
            app.histogramFunctionsPanel_7 = uipanel(app.SharpeningWorkSpacePanel);
            app.histogramFunctionsPanel_7.Title = 'Direct Mapping: Zero - Order';
            app.histogramFunctionsPanel_7.Visible = 'off';
            app.UIAxes_68 = uiaxes(app.histogramFunctionsPanel_7);
            title(app.UIAxes_68, 'Original Photo')
            app.UIAxes_68.XTick = [];
            app.UIAxes_68.XTickLabel = {'[ ]'};
            app.UIAxes_68.YTick = [];
            app.UIAxes_68.YTickLabel = {'[ ]'};
            app.UIAxes_69 = uiaxes(app.histogramFunctionsPanel_7);
            title(app.UIAxes_69, 'Resized Photo')
            app.UIAxes_69.XTick = [];
            app.UIAxes_69.XTickLabel = {'[ ]'};
            app.UIAxes_69.YTick = [];
            app.UIAxes_69.YTickLabel = {'[ ]'};
            app.FactorEditField_13Label = uilabel(app.histogramFunctionsPanel_7);
            app.FactorEditField_13Label.Text = 'Factor';
            app.FactorEditField_13 = uieditfield(app.histogramFunctionsPanel_7, 'numeric');
            app.FactorEditField_13.ValueDisplayFormat = '%.0f';
            app.ResizeButton_25 = uibutton(app.histogramFunctionsPanel_7, 'push');
            app.ResizeButton_25.Text = 'Resize';
            app.DirectMappingOneOrderPanel_7 = uipanel(app.SharpeningWorkSpacePanel);
            app.DirectMappingOneOrderPanel_7.Title = 'Direct Mapping: One - Order';
            app.DirectMappingOneOrderPanel_7.Visible = 'off';
            app.UIAxes_70 = uiaxes(app.DirectMappingOneOrderPanel_7);
            title(app.UIAxes_70, 'Original Photo')
            app.UIAxes_70.XTick = [];
            app.UIAxes_70.XTickLabel = {'[ ]'};
            app.UIAxes_70.YTick = [];
            app.UIAxes_70.YTickLabel = {'[ ]'};
            app.UIAxes_71 = uiaxes(app.DirectMappingOneOrderPanel_7);
            title(app.UIAxes_71, 'Resized Photo')
            app.UIAxes_71.XTick = [];
            app.UIAxes_71.XTickLabel = {'[ ]'};
            app.UIAxes_71.YTick = [];
            app.UIAxes_71.YTickLabel = {'[ ]'};
            app.FactorEditField_14Label = uilabel(app.DirectMappingOneOrderPanel_7);
            app.FactorEditField_14Label.Text = 'Factor';
            app.FactorEditField_14 = uieditfield(app.DirectMappingOneOrderPanel_7, 'numeric');
            app.FactorEditField_14.ValueDisplayFormat = '%.0f';
            app.ResizeButton_26 = uibutton(app.DirectMappingOneOrderPanel_7, 'push');
            app.ResizeButton_26.Text = 'Resize';
            app.ReverseMappingOneOrderPanel_7 = uipanel(app.SharpeningWorkSpacePanel);
            app.ReverseMappingOneOrderPanel_7.Title = 'Reverse Mapping: One - Order';
            app.ReverseMappingOneOrderPanel_7.Visible = 'off';
            app.UIAxes_72 = uiaxes(app.ReverseMappingOneOrderPanel_7);
            title(app.UIAxes_72, 'Original Photo')
            app.UIAxes_72.XTick = [];
            app.UIAxes_72.XTickLabel = {'[ ]'};
            app.UIAxes_72.YTick = [];
            app.UIAxes_72.YTickLabel = {'[ ]'};
            app.UIAxes_73 = uiaxes(app.ReverseMappingOneOrderPanel_7);
            title(app.UIAxes_73, 'Resized Photo')
            app.UIAxes_73.XTick = [];
            app.UIAxes_73.XTickLabel = {'[ ]'};
            app.UIAxes_73.YTick = [];
            app.UIAxes_73.YTickLabel = {'[ ]'};
            app.ResizeButton_27 = uibutton(app.ReverseMappingOneOrderPanel_7, 'push');
            app.ResizeButton_27.Text = 'Resize';
            app.RowFactorEditField_13Label = uilabel(app.ReverseMappingOneOrderPanel_7);
            app.RowFactorEditField_13Label.Text = 'Row Factor';
            app.RowFactorEditField_13 = uieditfield(app.ReverseMappingOneOrderPanel_7, 'numeric');
            app.RowFactorEditField_13.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_13Label = uilabel(app.ReverseMappingOneOrderPanel_7);
            app.ColFactorEditField_13Label.Text = 'Col Factor';
            app.ColFactorEditField_13 = uieditfield(app.ReverseMappingOneOrderPanel_7, 'numeric');
            app.ColFactorEditField_13.ValueDisplayFormat = '%.0f';
            app.ReverseMappingZeroOrderPanel_7 = uipanel(app.SharpeningWorkSpacePanel);
            app.ReverseMappingZeroOrderPanel_7.Title = 'Reverse Mapping: Zero - Order';
            app.ReverseMappingZeroOrderPanel_7.Visible = 'off';
            app.UIAxes_74 = uiaxes(app.ReverseMappingZeroOrderPanel_7);
            title(app.UIAxes_74, 'Original Photo')
            app.UIAxes_74.XTick = [];
            app.UIAxes_74.XTickLabel = {'[ ]'};
            app.UIAxes_74.YTick = [];
            app.UIAxes_74.YTickLabel = {'[ ]'};
            app.UIAxes_75 = uiaxes(app.ReverseMappingZeroOrderPanel_7);
            title(app.UIAxes_75, 'Resized Photo')
            app.UIAxes_75.XTick = [];
            app.UIAxes_75.XTickLabel = {'[ ]'};
            app.UIAxes_75.YTick = [];
            app.UIAxes_75.YTickLabel = {'[ ]'};
            app.ResizeButton_28 = uibutton(app.ReverseMappingZeroOrderPanel_7, 'push');
            app.ResizeButton_28.Text = 'Resize';
            app.RowFactorEditField_14Label = uilabel(app.ReverseMappingZeroOrderPanel_7);
            app.RowFactorEditField_14Label.Text = 'Row Factor';
            app.RowFactorEditField_14 = uieditfield(app.ReverseMappingZeroOrderPanel_7, 'numeric');
            app.RowFactorEditField_14.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_14Label = uilabel(app.ReverseMappingZeroOrderPanel_7);
            app.ColFactorEditField_14Label.Text = 'Col Factor';
            app.ColFactorEditField_14 = uieditfield(app.ReverseMappingZeroOrderPanel_7, 'numeric');
            app.ColFactorEditField_14.ValueDisplayFormat = '%.0f';
            app.ImageAdditionSubtractionPanel_4 = uipanel(app.SharpeningWorkSpacePanel);
            app.ImageAdditionSubtractionPanel_4.Title = 'Image Addition / Subtraction:';
            app.ImageAdditionSubtractionPanel_4.Visible = 'off';
            app.UIAxes_76 = uiaxes(app.ImageAdditionSubtractionPanel_4);
            title(app.UIAxes_76, ' Result')
            app.UIAxes_76.XTick = [];
            app.UIAxes_76.XTickLabel = {'[ ]'};
            app.UIAxes_76.YTick = [];
            app.UIAxes_76.YTickLabel = {'[ ]'};
            app.UIAxes_77 = uiaxes(app.ImageAdditionSubtractionPanel_4);
            title(app.UIAxes_77, 'Second Image')
            app.UIAxes_77.XTick = [];
            app.UIAxes_77.XTickLabel = {'[ ]'};
            app.UIAxes_77.YTick = [];
            app.UIAxes_77.YTickLabel = {'[ ]'};
            app.OffsetPanel_8 = uipanel(app.ImageAdditionSubtractionPanel_4);
            app.OffsetPanel_8.Title = 'Offset';
            app.OffsetPanel_8.Visible = 'off';
            app.OffsetEditField_8Label = uilabel(app.OffsetPanel_8);
            app.OffsetEditField_8Label.Text = 'Offset';
            app.OffsetEditField_8 = uieditfield(app.OffsetPanel_8, 'numeric');
            app.New_RangePanel_8 = uipanel(app.ImageAdditionSubtractionPanel_4);
            app.New_RangePanel_8.Title = 'New_Range:';
            app.New_RangePanel_8.Visible = 'off';
            app.NEW_MAXEditField_8Label = uilabel(app.New_RangePanel_8);
            app.NEW_MAXEditField_8Label.Text = 'NEW_MAX';
            app.NEW_MAXEditField_8 = uieditfield(app.New_RangePanel_8, 'numeric');
            app.NEW_MINEditField_8Label = uilabel(app.New_RangePanel_8);
            app.NEW_MINEditField_8Label.Text = 'NEW_MIN';
            app.NEW_MINEditField_8 = uieditfield(app.New_RangePanel_8, 'numeric');
            app.GammaPanel_8 = uipanel(app.ImageAdditionSubtractionPanel_4);
            app.GammaPanel_8.Title = 'Gamma :)';
            app.GammaPanel_8.Visible = 'off';
            app.GammaEditField_8Label = uilabel(app.GammaPanel_8);
            app.GammaEditField_8Label.Text = 'Gamma:';
            app.GammaEditField_8 = uieditfield(app.GammaPanel_8, 'numeric');
            app.EditField_16 = uieditfield(app.ImageAdditionSubtractionPanel_4, 'text');
            app.EditField_16.Visible = 'off';
            app.EditField_17 = uieditfield(app.ImageAdditionSubtractionPanel_4, 'text');
            app.UIAxes_78 = uiaxes(app.ImageAdditionSubtractionPanel_4);
            title(app.UIAxes_78, 'First Image')
            app.UIAxes_78.XTick = [];
            app.UIAxes_78.XTickLabel = {'[ ]'};
            app.UIAxes_78.YTick = [];
            app.UIAxes_78.YTickLabel = {'[ ]'};
            app.BitsPanel_4 = uipanel(app.SharpeningWorkSpacePanel);
            app.BitsPanel_4.Title = 'Bits:';
            app.BitsPanel_4.Visible = 'off';
            app.NoOfBitsEditField_4Label = uilabel(app.BitsPanel_4);
            app.NoOfBitsEditField_4Label.Text = 'No.Of .Bits';
            app.NoOfBitsEditField_4 = uieditfield(app.BitsPanel_4, 'numeric');
            app.NoOfBitsEditField_4.Limits = [0 Inf];
            app.SharpeningPanel = uipanel(app.SharpeningWorkSpacePanel);
            app.SharpeningPanel.Title = 'Sharpening :';
            app.OffsetPanel_9 = uipanel(app.SharpeningPanel);
            app.OffsetPanel_9.Title = 'Offset';
            app.OffsetPanel_9.Visible = 'off';
            app.OffsetEditField_9Label = uilabel(app.OffsetPanel_9);
            app.OffsetEditField_9Label.Text = 'Offset';
            app.OffsetEditField_9 = uieditfield(app.OffsetPanel_9, 'numeric');
            app.New_RangePanel_9 = uipanel(app.SharpeningPanel);
            app.New_RangePanel_9.Title = 'New_Range:';
            app.New_RangePanel_9.Visible = 'off';
            app.NEW_MAXEditField_9Label = uilabel(app.New_RangePanel_9);
            app.NEW_MAXEditField_9Label.Text = 'NEW_MAX';
            app.NEW_MAXEditField_9 = uieditfield(app.New_RangePanel_9, 'numeric');
            app.NEW_MINEditField_9Label = uilabel(app.New_RangePanel_9);
            app.NEW_MINEditField_9Label.Text = 'NEW_MIN';
            app.NEW_MINEditField_9 = uieditfield(app.New_RangePanel_9, 'numeric');
            app.GammaPanel_9 = uipanel(app.SharpeningPanel);
            app.GammaPanel_9.Title = 'Gamma :)';
            app.GammaPanel_9.Visible = 'off';
            app.GammaEditField_9Label = uilabel(app.GammaPanel_9);
            app.GammaEditField_9Label.Text = 'Gamma:';
            app.GammaEditField_9 = uieditfield(app.GammaPanel_9, 'numeric');
            app.MaskPanel_3 = uipanel(app.SharpeningPanel);
            app.MaskPanel_3.Title = 'Mask:';
            app.MaskPanel_3.Visible = 'off';
            app.MaskSizeEditField_3Label = uilabel(app.MaskPanel_3);
            app.MaskSizeEditField_3Label.Text = 'Mask Size:';
            app.MaskSizeEditField_3 = uieditfield(app.MaskPanel_3, 'numeric');
            app.MaskSizeEditField_3.Limits = [1 Inf];
            app.MaskSizeEditField_3.ValueDisplayFormat = '%.0f';
            app.MaskSizeEditField_3.Value = 1;
            app.SigmaPanel_3 = uipanel(app.SharpeningPanel);
            app.SigmaPanel_3.Title = 'Sigma:';
            app.SigmaPanel_3.Visible = 'off';
            app.SigmaEditField_3Label = uilabel(app.SigmaPanel_3);
            app.SigmaEditField_3Label.Text = 'Sigma σ:';
            app.SigmaEditField_3 = uieditfield(app.SigmaPanel_3, 'numeric');
            app.SigmaEditField_3.Limits = [0 Inf];
            app.UIAxes_79 = uiaxes(app.SharpeningPanel);
            title(app.UIAxes_79, 'Original ')
            app.UIAxes_79.XTick = [];
            app.UIAxes_79.XTickLabel = {'[ ]'};
            app.UIAxes_79.YTick = [];
            app.UIAxes_79.YTickLabel = {'[ ]'};
            app.UIAxes_80 = uiaxes(app.SharpeningPanel);
            title(app.UIAxes_80, 'Result')
            app.UIAxes_80.XTick = [];
            app.UIAxes_80.XTickLabel = {'[ ]'};
            app.UIAxes_80.YTick = [];
            app.UIAxes_80.YTickLabel = {'[ ]'};
            app.ApplyButton_2 = uibutton(app.SharpeningWorkSpacePanel, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Icon = 'check.png';
            app.ApplyButton_2.Text = 'Apply';
            app.UnsharpenMaskingWorkSpacePanel = uipanel(app.ImageProcessingAPPPanel);
            app.UnsharpenMaskingWorkSpacePanel.Title = 'Unsharpen Masking Work Space';
            app.UnsharpenMaskingWorkSpacePanel.Visible = 'off';
            app.UploadPhotoButton_8 = uibutton(app.UnsharpenMaskingWorkSpacePanel, 'push');
            app.UploadPhotoButton_8.ButtonPushedFcn = createCallbackFcn(app, @UploadPhotoButton_8Pushed, true);
            app.UploadPhotoButton_8.Icon = 'gallery.png';
            app.UploadPhotoButton_8.Text = 'Upload Photo';
            app.EditField_18 = uieditfield(app.UnsharpenMaskingWorkSpacePanel, 'text');
            app.histogramFunctionsPanel_8 = uipanel(app.UnsharpenMaskingWorkSpacePanel);
            app.histogramFunctionsPanel_8.Title = 'Direct Mapping: Zero - Order';
            app.histogramFunctionsPanel_8.Visible = 'off';
            app.UIAxes_81 = uiaxes(app.histogramFunctionsPanel_8);
            title(app.UIAxes_81, 'Original Photo')
            app.UIAxes_81.XTick = [];
            app.UIAxes_81.XTickLabel = {'[ ]'};
            app.UIAxes_81.YTick = [];
            app.UIAxes_81.YTickLabel = {'[ ]'};
            app.UIAxes_82 = uiaxes(app.histogramFunctionsPanel_8);
            title(app.UIAxes_82, 'Resized Photo')
            app.UIAxes_82.XTick = [];
            app.UIAxes_82.XTickLabel = {'[ ]'};
            app.UIAxes_82.YTick = [];
            app.UIAxes_82.YTickLabel = {'[ ]'};
            app.FactorEditField_15Label = uilabel(app.histogramFunctionsPanel_8);
            app.FactorEditField_15Label.Text = 'Factor';
            app.FactorEditField_15 = uieditfield(app.histogramFunctionsPanel_8, 'numeric');
            app.FactorEditField_15.ValueDisplayFormat = '%.0f';
            app.ResizeButton_29 = uibutton(app.histogramFunctionsPanel_8, 'push');
            app.ResizeButton_29.Text = 'Resize';
            app.DirectMappingOneOrderPanel_8 = uipanel(app.UnsharpenMaskingWorkSpacePanel);
            app.DirectMappingOneOrderPanel_8.Title = 'Direct Mapping: One - Order';
            app.DirectMappingOneOrderPanel_8.Visible = 'off';
            app.UIAxes_83 = uiaxes(app.DirectMappingOneOrderPanel_8);
            title(app.UIAxes_83, 'Original Photo')
            app.UIAxes_83.XTick = [];
            app.UIAxes_83.XTickLabel = {'[ ]'};
            app.UIAxes_83.YTick = [];
            app.UIAxes_83.YTickLabel = {'[ ]'};
            app.UIAxes_84 = uiaxes(app.DirectMappingOneOrderPanel_8);
            title(app.UIAxes_84, 'Resized Photo')
            app.UIAxes_84.XTick = [];
            app.UIAxes_84.XTickLabel = {'[ ]'};
            app.UIAxes_84.YTick = [];
            app.UIAxes_84.YTickLabel = {'[ ]'};
            app.FactorEditField_16Label = uilabel(app.DirectMappingOneOrderPanel_8);
            app.FactorEditField_16Label.Text = 'Factor';
            app.FactorEditField_16 = uieditfield(app.DirectMappingOneOrderPanel_8, 'numeric');
            app.FactorEditField_16.ValueDisplayFormat = '%.0f';
            app.ResizeButton_30 = uibutton(app.DirectMappingOneOrderPanel_8, 'push');
            app.ResizeButton_30.Text = 'Resize';
            app.ReverseMappingOneOrderPanel_8 = uipanel(app.UnsharpenMaskingWorkSpacePanel);
            app.ReverseMappingOneOrderPanel_8.Title = 'Reverse Mapping: One - Order';
            app.ReverseMappingOneOrderPanel_8.Visible = 'off';
            app.UIAxes_85 = uiaxes(app.ReverseMappingOneOrderPanel_8);
            title(app.UIAxes_85, 'Original Photo')
            app.UIAxes_85.XTick = [];
            app.UIAxes_85.XTickLabel = {'[ ]'};
            app.UIAxes_85.YTick = [];
            app.UIAxes_85.YTickLabel = {'[ ]'};
            app.UIAxes_86 = uiaxes(app.ReverseMappingOneOrderPanel_8);
            title(app.UIAxes_86, 'Resized Photo')
            app.UIAxes_86.XTick = [];
            app.UIAxes_86.XTickLabel = {'[ ]'};
            app.UIAxes_86.YTick = [];
            app.UIAxes_86.YTickLabel = {'[ ]'};
            app.ResizeButton_31 = uibutton(app.ReverseMappingOneOrderPanel_8, 'push');
            app.ResizeButton_31.Text = 'Resize';
            app.RowFactorEditField_15Label = uilabel(app.ReverseMappingOneOrderPanel_8);
            app.RowFactorEditField_15Label.Text = 'Row Factor';
            app.RowFactorEditField_15 = uieditfield(app.ReverseMappingOneOrderPanel_8, 'numeric');
            app.RowFactorEditField_15.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_15Label = uilabel(app.ReverseMappingOneOrderPanel_8);
            app.ColFactorEditField_15Label.Text = 'Col Factor';
            app.ColFactorEditField_15 = uieditfield(app.ReverseMappingOneOrderPanel_8, 'numeric');
            app.ColFactorEditField_15.ValueDisplayFormat = '%.0f';
            app.ReverseMappingZeroOrderPanel_8 = uipanel(app.UnsharpenMaskingWorkSpacePanel);
            app.ReverseMappingZeroOrderPanel_8.Title = 'Reverse Mapping: Zero - Order';
            app.ReverseMappingZeroOrderPanel_8.Visible = 'off';
            app.UIAxes_87 = uiaxes(app.ReverseMappingZeroOrderPanel_8);
            title(app.UIAxes_87, 'Original Photo')
            app.UIAxes_87.XTick = [];
            app.UIAxes_87.XTickLabel = {'[ ]'};
            app.UIAxes_87.YTick = [];
            app.UIAxes_87.YTickLabel = {'[ ]'};
            app.UIAxes_88 = uiaxes(app.ReverseMappingZeroOrderPanel_8);
            title(app.UIAxes_88, 'Resized Photo')
            app.UIAxes_88.XTick = [];
            app.UIAxes_88.XTickLabel = {'[ ]'};
            app.UIAxes_88.YTick = [];
            app.UIAxes_88.YTickLabel = {'[ ]'};
            app.ResizeButton_32 = uibutton(app.ReverseMappingZeroOrderPanel_8, 'push');
            app.ResizeButton_32.Text = 'Resize';
            app.RowFactorEditField_16Label = uilabel(app.ReverseMappingZeroOrderPanel_8);
            app.RowFactorEditField_16Label.Text = 'Row Factor';
            app.RowFactorEditField_16 = uieditfield(app.ReverseMappingZeroOrderPanel_8, 'numeric');
            app.RowFactorEditField_16.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_16Label = uilabel(app.ReverseMappingZeroOrderPanel_8);
            app.ColFactorEditField_16Label.Text = 'Col Factor';
            app.ColFactorEditField_16 = uieditfield(app.ReverseMappingZeroOrderPanel_8, 'numeric');
            app.ColFactorEditField_16.ValueDisplayFormat = '%.0f';
            app.ImageAdditionSubtractionPanel_5 = uipanel(app.UnsharpenMaskingWorkSpacePanel);
            app.ImageAdditionSubtractionPanel_5.Title = 'Image Addition / Subtraction:';
            app.ImageAdditionSubtractionPanel_5.Visible = 'off';
            app.UIAxes_89 = uiaxes(app.ImageAdditionSubtractionPanel_5);
            title(app.UIAxes_89, ' Result')
            app.UIAxes_89.XTick = [];
            app.UIAxes_89.XTickLabel = {'[ ]'};
            app.UIAxes_89.YTick = [];
            app.UIAxes_89.YTickLabel = {'[ ]'};
            app.UIAxes_90 = uiaxes(app.ImageAdditionSubtractionPanel_5);
            title(app.UIAxes_90, 'Second Image')
            app.UIAxes_90.XTick = [];
            app.UIAxes_90.XTickLabel = {'[ ]'};
            app.UIAxes_90.YTick = [];
            app.UIAxes_90.YTickLabel = {'[ ]'};
            app.OffsetPanel_10 = uipanel(app.ImageAdditionSubtractionPanel_5);
            app.OffsetPanel_10.Title = 'Offset';
            app.OffsetPanel_10.Visible = 'off';
            app.OffsetEditField_10Label = uilabel(app.OffsetPanel_10);
            app.OffsetEditField_10Label.Text = 'Offset';
            app.OffsetEditField_10 = uieditfield(app.OffsetPanel_10, 'numeric');
            app.New_RangePanel_10 = uipanel(app.ImageAdditionSubtractionPanel_5);
            app.New_RangePanel_10.Title = 'New_Range:';
            app.New_RangePanel_10.Visible = 'off';
            app.NEW_MAXEditField_10Label = uilabel(app.New_RangePanel_10);
            app.NEW_MAXEditField_10Label.Text = 'NEW_MAX';
            app.NEW_MAXEditField_10 = uieditfield(app.New_RangePanel_10, 'numeric');
            app.NEW_MINEditField_10Label = uilabel(app.New_RangePanel_10);
            app.NEW_MINEditField_10Label.Text = 'NEW_MIN';
            app.NEW_MINEditField_10 = uieditfield(app.New_RangePanel_10, 'numeric');
            app.GammaPanel_10 = uipanel(app.ImageAdditionSubtractionPanel_5);
            app.GammaPanel_10.Title = 'Gamma :)';
            app.GammaPanel_10.Visible = 'off';
            app.GammaEditField_10Label = uilabel(app.GammaPanel_10);
            app.GammaEditField_10Label.Text = 'Gamma:';
            app.GammaEditField_10 = uieditfield(app.GammaPanel_10, 'numeric');
            app.EditField_19 = uieditfield(app.ImageAdditionSubtractionPanel_5, 'text');
            app.EditField_19.Visible = 'off';
            app.EditField_20 = uieditfield(app.ImageAdditionSubtractionPanel_5, 'text');
            app.UIAxes_91 = uiaxes(app.ImageAdditionSubtractionPanel_5);
            title(app.UIAxes_91, 'First Image')
            app.UIAxes_91.XTick = [];
            app.UIAxes_91.XTickLabel = {'[ ]'};
            app.UIAxes_91.YTick = [];
            app.UIAxes_91.YTickLabel = {'[ ]'};
            app.BitsPanel_5 = uipanel(app.UnsharpenMaskingWorkSpacePanel);
            app.BitsPanel_5.Title = 'Bits:';
            app.BitsPanel_5.Visible = 'off';
            app.NoOfBitsEditField_5Label = uilabel(app.BitsPanel_5);
            app.NoOfBitsEditField_5Label.Text = 'No.Of .Bits';
            app.NoOfBitsEditField_5 = uieditfield(app.BitsPanel_5, 'numeric');
            app.NoOfBitsEditField_5.Limits = [0 Inf];
            app.UnsharpenMaskingPanel = uipanel(app.UnsharpenMaskingWorkSpacePanel);
            app.UnsharpenMaskingPanel.Title = 'Unsharpen Masking:';
            app.OffsetPanel_11 = uipanel(app.UnsharpenMaskingPanel);
            app.OffsetPanel_11.Title = 'Offset';
            app.OffsetPanel_11.Visible = 'off';
            app.OffsetEditField_11Label = uilabel(app.OffsetPanel_11);
            app.OffsetEditField_11Label.Text = 'Offset';
            app.OffsetEditField_11 = uieditfield(app.OffsetPanel_11, 'numeric');
            app.New_RangePanel_11 = uipanel(app.UnsharpenMaskingPanel);
            app.New_RangePanel_11.Title = 'New_Range:';
            app.New_RangePanel_11.Visible = 'off';
            app.NEW_MAXEditField_11Label = uilabel(app.New_RangePanel_11);
            app.NEW_MAXEditField_11Label.Text = 'NEW_MAX';
            app.NEW_MAXEditField_11 = uieditfield(app.New_RangePanel_11, 'numeric');
            app.NEW_MINEditField_11Label = uilabel(app.New_RangePanel_11);
            app.NEW_MINEditField_11Label.Text = 'NEW_MIN';
            app.NEW_MINEditField_11 = uieditfield(app.New_RangePanel_11, 'numeric');
            app.GammaPanel_11 = uipanel(app.UnsharpenMaskingPanel);
            app.GammaPanel_11.Title = 'Gamma :)';
            app.GammaPanel_11.Visible = 'off';
            app.GammaEditField_11Label = uilabel(app.GammaPanel_11);
            app.GammaEditField_11Label.Text = 'Gamma:';
            app.GammaEditField_11 = uieditfield(app.GammaPanel_11, 'numeric');
            app.MaskPanel_4 = uipanel(app.UnsharpenMaskingPanel);
            app.MaskPanel_4.Title = 'Mask:';
            app.MaskPanel_4.Visible = 'off';
            app.MaskSizeEditField_4Label = uilabel(app.MaskPanel_4);
            app.MaskSizeEditField_4Label.Text = 'Mask Size:';
            app.MaskSizeEditField_4 = uieditfield(app.MaskPanel_4, 'numeric');
            app.MaskSizeEditField_4.Limits = [1 Inf];
            app.MaskSizeEditField_4.ValueDisplayFormat = '%.0f';
            app.MaskSizeEditField_4.Value = 1;
            app.SigmaPanel_4 = uipanel(app.UnsharpenMaskingPanel);
            app.SigmaPanel_4.Title = 'Sigma:';
            app.SigmaPanel_4.Visible = 'off';
            app.SigmaEditField_4Label = uilabel(app.SigmaPanel_4);
            app.SigmaEditField_4Label.Text = 'Sigma σ:';
            app.SigmaEditField_4 = uieditfield(app.SigmaPanel_4, 'numeric');
            app.SigmaEditField_4.Limits = [0 Inf];
            app.UIAxes_92 = uiaxes(app.UnsharpenMaskingPanel);
            title(app.UIAxes_92, 'Original ')
            app.UIAxes_92.XTick = [];
            app.UIAxes_92.XTickLabel = {'[ ]'};
            app.UIAxes_92.YTick = [];
            app.UIAxes_92.YTickLabel = {'[ ]'};
            app.UIAxes_93 = uiaxes(app.UnsharpenMaskingPanel);
            title(app.UIAxes_93, 'Result')
            app.UIAxes_93.XTick = [];
            app.UIAxes_93.XTickLabel = {'[ ]'};
            app.UIAxes_93.YTick = [];
            app.UIAxes_93.YTickLabel = {'[ ]'};
            app.ApplyButton_3 = uibutton(app.UnsharpenMaskingWorkSpacePanel, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Icon = 'check.png';
            app.ApplyButton_3.Text = 'Apply';
            app.SigmaLabel = uilabel(app.UnsharpenMaskingWorkSpacePanel);
            app.SigmaLabel.Text = 'Sigma σ:';
            app.SigmaEditField_5 = uieditfield(app.UnsharpenMaskingWorkSpacePanel, 'numeric');
            app.SigmaEditField_5.Limits = [0 Inf];
            app.MappingWorkSpacePanel = uipanel(app.ImageProcessingAPPPanel);
            app.MappingWorkSpacePanel.Title = 'Mapping Work Space';
            app.MappingWorkSpacePanel.Visible = 'off';
            app.MappingWorkSpacePanel.SizeChangedFcn = createCallbackFcn(app, @MappingWorkSpacePanelSizeChanged, true);
            app.UploadPhotoButton = uibutton(app.MappingWorkSpacePanel, 'push');
            app.UploadPhotoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadPhotoButtonPushed, true);
            app.UploadPhotoButton.Icon = 'gallery.png';
            app.UploadPhotoButton.Text = 'Upload Photo';
            app.EditField = uieditfield(app.MappingWorkSpacePanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DirectMappingZeroOrderPanel = uipanel(app.MappingWorkSpacePanel);
            app.DirectMappingZeroOrderPanel.Title = 'Direct Mapping: Zero - Order';
            app.DirectMappingZeroOrderPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.DirectMappingZeroOrderPanel);
            title(app.UIAxes, 'Original Photo')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.UIAxes_2 = uiaxes(app.DirectMappingZeroOrderPanel);
            title(app.UIAxes_2, 'Resized Photo')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = {'[ ]'};
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = {'[ ]'};
            app.FactorEditFieldLabel = uilabel(app.DirectMappingZeroOrderPanel);
            app.FactorEditFieldLabel.Text = 'Factor';
            app.FactorEditField = uieditfield(app.DirectMappingZeroOrderPanel, 'numeric');
            app.FactorEditField.ValueDisplayFormat = '%.0f';
            app.ResizeButton = uibutton(app.DirectMappingZeroOrderPanel, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.ReverseMappingZeroOrderButton = uibutton(app.MappingWorkSpacePanel, 'push');
            app.ReverseMappingZeroOrderButton.ButtonPushedFcn = createCallbackFcn(app, @ReverseMappingZeroOrderButtonPushed, true);
            app.ReverseMappingZeroOrderButton.Text = 'Reverse Mapping: Zero – Order ';
            app.DirectMappingOneOrderButton = uibutton(app.MappingWorkSpacePanel, 'push');
            app.DirectMappingOneOrderButton.ButtonPushedFcn = createCallbackFcn(app, @DirectMappingOneOrderButtonPushed, true);
            app.DirectMappingOneOrderButton.Text = 'Direct Mapping: One – Order ';
            app.DirectMappingZeroOrderButton = uibutton(app.MappingWorkSpacePanel, 'push');
            app.DirectMappingZeroOrderButton.ButtonPushedFcn = createCallbackFcn(app, @DirectMappingZeroOrderButtonPushed, true);
            app.DirectMappingZeroOrderButton.Text = 'Direct Mapping: Zero – Order ';
            app.ReverseMappingOneOrderButton_4 = uibutton(app.MappingWorkSpacePanel, 'push');
            app.ReverseMappingOneOrderButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReverseMappingOneOrderButton_4Pushed, true);
            app.ReverseMappingOneOrderButton_4.Text = 'Reverse Mapping: One – Order ';
            app.DirectMappingOneOrderPanel = uipanel(app.MappingWorkSpacePanel);
            app.DirectMappingOneOrderPanel.Title = 'Direct Mapping: One - Order';
            app.DirectMappingOneOrderPanel.Visible = 'off';
            app.FactorEditField_2Label = uilabel(app.DirectMappingOneOrderPanel);
            app.FactorEditField_2Label.Text = 'Factor';
            app.FactorEditField_2 = uieditfield(app.DirectMappingOneOrderPanel, 'numeric');
            app.FactorEditField_2.ValueDisplayFormat = '%.0f';
            app.ResizeButton_2 = uibutton(app.DirectMappingOneOrderPanel, 'push');
            app.ResizeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResizeButton_2Pushed, true);
            app.ResizeButton_2.Text = 'Resize';
            app.UIAxes_3 = uiaxes(app.DirectMappingOneOrderPanel);
            title(app.UIAxes_3, 'Original Photo')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.XTickLabel = {'[ ]'};
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.YTickLabel = {'[ ]'};
            app.UIAxes_4 = uiaxes(app.DirectMappingOneOrderPanel);
            title(app.UIAxes_4, 'Resized Photo')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.XTickLabel = {'[ ]'};
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.YTickLabel = {'[ ]'};
            app.ReverseMappingZeroOrderPanel = uipanel(app.MappingWorkSpacePanel);
            app.ReverseMappingZeroOrderPanel.Title = 'Reverse Mapping: Zero - Order';
            app.ReverseMappingZeroOrderPanel.Visible = 'off';
            app.UIAxes_5 = uiaxes(app.ReverseMappingZeroOrderPanel);
            title(app.UIAxes_5, 'Original Photo')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.XTickLabel = {'[ ]'};
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.YTickLabel = {'[ ]'};
            app.UIAxes_6 = uiaxes(app.ReverseMappingZeroOrderPanel);
            title(app.UIAxes_6, 'Resized Photo')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.XTickLabel = {'[ ]'};
            app.UIAxes_6.YTick = [];
            app.UIAxes_6.YTickLabel = {'[ ]'};
            app.ResizeButton_3 = uibutton(app.ReverseMappingZeroOrderPanel, 'push');
            app.ResizeButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResizeButton_3Pushed, true);
            app.ResizeButton_3.Text = 'Resize';
            app.RowFactorEditFieldLabel = uilabel(app.ReverseMappingZeroOrderPanel);
            app.RowFactorEditFieldLabel.Text = 'Row Factor';
            app.RowFactorEditField = uieditfield(app.ReverseMappingZeroOrderPanel, 'numeric');
            app.RowFactorEditField.ValueDisplayFormat = '%.0f';
            app.ColFactorEditFieldLabel = uilabel(app.ReverseMappingZeroOrderPanel);
            app.ColFactorEditFieldLabel.Text = 'Col Factor';
            app.ColFactorEditField = uieditfield(app.ReverseMappingZeroOrderPanel, 'numeric');
            app.ColFactorEditField.ValueDisplayFormat = '%.0f';
            app.ReverseMappingOneOrderPanel = uipanel(app.MappingWorkSpacePanel);
            app.ReverseMappingOneOrderPanel.Title = 'Reverse Mapping: One - Order';
            app.ReverseMappingOneOrderPanel.Visible = 'off';
            app.UIAxes_7 = uiaxes(app.ReverseMappingOneOrderPanel);
            title(app.UIAxes_7, 'Original Photo')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.XTickLabel = {'[ ]'};
            app.UIAxes_7.YTick = [];
            app.UIAxes_7.YTickLabel = {'[ ]'};
            app.UIAxes_8 = uiaxes(app.ReverseMappingOneOrderPanel);
            title(app.UIAxes_8, 'Resized Photo')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.XTickLabel = {'[ ]'};
            app.UIAxes_8.YTick = [];
            app.UIAxes_8.YTickLabel = {'[ ]'};
            app.ResizeButton_4 = uibutton(app.ReverseMappingOneOrderPanel, 'push');
            app.ResizeButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResizeButton_4Pushed, true);
            app.ResizeButton_4.Text = 'Resize';
            app.RowFactorEditField_2Label = uilabel(app.ReverseMappingOneOrderPanel);
            app.RowFactorEditField_2Label.Text = 'Row Factor';
            app.RowFactorEditField_2 = uieditfield(app.ReverseMappingOneOrderPanel, 'numeric');
            app.RowFactorEditField_2.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_2Label = uilabel(app.ReverseMappingOneOrderPanel);
            app.ColFactorEditField_2Label.Text = 'Col Factor';
            app.ColFactorEditField_2 = uieditfield(app.ReverseMappingOneOrderPanel, 'numeric');
            app.ColFactorEditField_2.ValueDisplayFormat = '%.0f';
            app.HistogramWorkSpacePanel = uipanel(app.ImageProcessingAppUIFigure);
            app.HistogramWorkSpacePanel.Title = 'Histogram Work Space';
            app.HistogramWorkSpacePanel.Visible = 'off';
            app.UploadPhotoButton_3 = uibutton(app.HistogramWorkSpacePanel, 'push');
            app.UploadPhotoButton_3.ButtonPushedFcn = createCallbackFcn(app, @UploadPhotoButton_3Pushed, true);
            app.UploadPhotoButton_3.Icon = 'gallery.png';
            app.UploadPhotoButton_3.Text = 'Upload Photo';
            app.EditField_3 = uieditfield(app.HistogramWorkSpacePanel, 'text');
            app.histogramFunctionsPanel_3 = uipanel(app.HistogramWorkSpacePanel);
            app.histogramFunctionsPanel_3.Title = 'Direct Mapping: Zero - Order';
            app.histogramFunctionsPanel_3.Visible = 'off';
            app.UIAxes_19 = uiaxes(app.histogramFunctionsPanel_3);
            title(app.UIAxes_19, 'Original Photo')
            app.UIAxes_19.XTick = [];
            app.UIAxes_19.XTickLabel = {'[ ]'};
            app.UIAxes_19.YTick = [];
            app.UIAxes_19.YTickLabel = {'[ ]'};
            app.UIAxes_20 = uiaxes(app.histogramFunctionsPanel_3);
            title(app.UIAxes_20, 'Resized Photo')
            app.UIAxes_20.XTick = [];
            app.UIAxes_20.XTickLabel = {'[ ]'};
            app.UIAxes_20.YTick = [];
            app.UIAxes_20.YTickLabel = {'[ ]'};
            app.FactorEditField_5Label = uilabel(app.histogramFunctionsPanel_3);
            app.FactorEditField_5Label.Text = 'Factor';
            app.FactorEditField_5 = uieditfield(app.histogramFunctionsPanel_3, 'numeric');
            app.FactorEditField_5.ValueDisplayFormat = '%.0f';
            app.ResizeButton_9 = uibutton(app.histogramFunctionsPanel_3, 'push');
            app.ResizeButton_9.Text = 'Resize';
            app.DirectMappingOneOrderPanel_3 = uipanel(app.HistogramWorkSpacePanel);
            app.DirectMappingOneOrderPanel_3.Title = 'Direct Mapping: One - Order';
            app.DirectMappingOneOrderPanel_3.Visible = 'off';
            app.UIAxes_21 = uiaxes(app.DirectMappingOneOrderPanel_3);
            title(app.UIAxes_21, 'Original Photo')
            app.UIAxes_21.XTick = [];
            app.UIAxes_21.XTickLabel = {'[ ]'};
            app.UIAxes_21.YTick = [];
            app.UIAxes_21.YTickLabel = {'[ ]'};
            app.UIAxes_22 = uiaxes(app.DirectMappingOneOrderPanel_3);
            title(app.UIAxes_22, 'Resized Photo')
            app.UIAxes_22.XTick = [];
            app.UIAxes_22.XTickLabel = {'[ ]'};
            app.UIAxes_22.YTick = [];
            app.UIAxes_22.YTickLabel = {'[ ]'};
            app.FactorEditField_6Label = uilabel(app.DirectMappingOneOrderPanel_3);
            app.FactorEditField_6Label.Text = 'Factor';
            app.FactorEditField_6 = uieditfield(app.DirectMappingOneOrderPanel_3, 'numeric');
            app.FactorEditField_6.ValueDisplayFormat = '%.0f';
            app.ResizeButton_10 = uibutton(app.DirectMappingOneOrderPanel_3, 'push');
            app.ResizeButton_10.Text = 'Resize';
            app.ReverseMappingOneOrderPanel_3 = uipanel(app.HistogramWorkSpacePanel);
            app.ReverseMappingOneOrderPanel_3.Title = 'Reverse Mapping: One - Order';
            app.ReverseMappingOneOrderPanel_3.Visible = 'off';
            app.UIAxes_23 = uiaxes(app.ReverseMappingOneOrderPanel_3);
            title(app.UIAxes_23, 'Original Photo')
            app.UIAxes_23.XTick = [];
            app.UIAxes_23.XTickLabel = {'[ ]'};
            app.UIAxes_23.YTick = [];
            app.UIAxes_23.YTickLabel = {'[ ]'};
            app.UIAxes_24 = uiaxes(app.ReverseMappingOneOrderPanel_3);
            title(app.UIAxes_24, 'Resized Photo')
            app.UIAxes_24.XTick = [];
            app.UIAxes_24.XTickLabel = {'[ ]'};
            app.UIAxes_24.YTick = [];
            app.UIAxes_24.YTickLabel = {'[ ]'};
            app.ResizeButton_11 = uibutton(app.ReverseMappingOneOrderPanel_3, 'push');
            app.ResizeButton_11.Text = 'Resize';
            app.RowFactorEditField_5Label = uilabel(app.ReverseMappingOneOrderPanel_3);
            app.RowFactorEditField_5Label.Text = 'Row Factor';
            app.RowFactorEditField_5 = uieditfield(app.ReverseMappingOneOrderPanel_3, 'numeric');
            app.RowFactorEditField_5.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_5Label = uilabel(app.ReverseMappingOneOrderPanel_3);
            app.ColFactorEditField_5Label.Text = 'Col Factor';
            app.ColFactorEditField_5 = uieditfield(app.ReverseMappingOneOrderPanel_3, 'numeric');
            app.ColFactorEditField_5.ValueDisplayFormat = '%.0f';
            app.ReverseMappingZeroOrderPanel_3 = uipanel(app.HistogramWorkSpacePanel);
            app.ReverseMappingZeroOrderPanel_3.Title = 'Reverse Mapping: Zero - Order';
            app.ReverseMappingZeroOrderPanel_3.Visible = 'off';
            app.UIAxes_25 = uiaxes(app.ReverseMappingZeroOrderPanel_3);
            title(app.UIAxes_25, 'Original Photo')
            app.UIAxes_25.XTick = [];
            app.UIAxes_25.XTickLabel = {'[ ]'};
            app.UIAxes_25.YTick = [];
            app.UIAxes_25.YTickLabel = {'[ ]'};
            app.UIAxes_26 = uiaxes(app.ReverseMappingZeroOrderPanel_3);
            title(app.UIAxes_26, 'Resized Photo')
            app.UIAxes_26.XTick = [];
            app.UIAxes_26.XTickLabel = {'[ ]'};
            app.UIAxes_26.YTick = [];
            app.UIAxes_26.YTickLabel = {'[ ]'};
            app.ResizeButton_12 = uibutton(app.ReverseMappingZeroOrderPanel_3, 'push');
            app.ResizeButton_12.Text = 'Resize';
            app.RowFactorEditField_6Label = uilabel(app.ReverseMappingZeroOrderPanel_3);
            app.RowFactorEditField_6Label.Text = 'Row Factor';
            app.RowFactorEditField_6 = uieditfield(app.ReverseMappingZeroOrderPanel_3, 'numeric');
            app.RowFactorEditField_6.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_6Label = uilabel(app.ReverseMappingZeroOrderPanel_3);
            app.ColFactorEditField_6Label.Text = 'Col Factor';
            app.ColFactorEditField_6 = uieditfield(app.ReverseMappingZeroOrderPanel_3, 'numeric');
            app.ColFactorEditField_6.ValueDisplayFormat = '%.0f';
            app.HistogramPanel = uipanel(app.HistogramWorkSpacePanel);
            app.HistogramPanel.Title = 'Histogram :';
            app.UIAxes_27 = uiaxes(app.HistogramPanel);
            title(app.UIAxes_27, 'Original Photo')
            app.UIAxes_27.XTick = [];
            app.UIAxes_27.XTickLabel = {'[ ]'};
            app.UIAxes_27.YTick = [];
            app.UIAxes_27.YTickLabel = {'[ ]'};
            app.UIAxes_28 = uiaxes(app.HistogramPanel);
            title(app.UIAxes_28, 'Result')
            app.UIAxes_28.XTick = [];
            app.UIAxes_28.XTickLabel = {'[ ]'};
            app.UIAxes_28.YTick = [];
            app.UIAxes_28.YTickLabel = {'[ ]'};
            app.OffsetPanel = uipanel(app.HistogramPanel);
            app.OffsetPanel.Title = 'Offset';
            app.OffsetPanel.Visible = 'off';
            app.OffsetEditFieldLabel = uilabel(app.OffsetPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.OffsetPanel, 'numeric');
            app.New_RangePanel = uipanel(app.HistogramPanel);
            app.New_RangePanel.Title = 'New_Range:';
            app.New_RangePanel.Visible = 'off';
            app.NEW_MAXEditFieldLabel = uilabel(app.New_RangePanel);
            app.NEW_MAXEditFieldLabel.Text = 'NEW_MAX';
            app.NEW_MAXEditField = uieditfield(app.New_RangePanel, 'numeric');
            app.NEW_MINEditFieldLabel = uilabel(app.New_RangePanel);
            app.NEW_MINEditFieldLabel.Text = 'NEW_MIN';
            app.NEW_MINEditField = uieditfield(app.New_RangePanel, 'numeric');
            app.GammaPanel = uipanel(app.HistogramPanel);
            app.GammaPanel.Title = 'Gamma :)';
            app.GammaPanel.Visible = 'off';
            app.GammaEditFieldLabel = uilabel(app.GammaPanel);
            app.GammaEditFieldLabel.Text = 'Gamma:';
            app.GammaEditField = uieditfield(app.GammaPanel, 'numeric');
            app.ApplyButton_5 = uibutton(app.HistogramPanel, 'push');
            app.ApplyButton_5.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_5Pushed, true);
            app.ApplyButton_5.Icon = 'check.png';
            app.ApplyButton_5.Visible = 'off';
            app.ApplyButton_5.Text = 'Apply';
            app.ApplyButton_6 = uibutton(app.HistogramPanel, 'push');
            app.ApplyButton_6.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_6Pushed, true);
            app.ApplyButton_6.Icon = 'check.png';
            app.ApplyButton_6.Visible = 'off';
            app.ApplyButton_6.Text = 'Apply';
            app.ApplyButton_7 = uibutton(app.HistogramPanel, 'push');
            app.ApplyButton_7.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_7Pushed, true);
            app.ApplyButton_7.Icon = 'check.png';
            app.ApplyButton_7.Visible = 'off';
            app.ApplyButton_7.Text = 'Apply';
            app.HistogramMatchingPanel = uipanel(app.HistogramWorkSpacePanel);
            app.HistogramMatchingPanel.Title = 'Histogram Matching :';
            app.HistogramMatchingPanel.Visible = 'off';
            app.UIAxes_94 = uiaxes(app.HistogramMatchingPanel);
            title(app.UIAxes_94, 'Histogram_2')
            app.UIAxes_94.XTick = [];
            app.UIAxes_94.XTickLabel = {'[ ]'};
            app.UIAxes_94.YTick = [];
            app.UIAxes_94.YTickLabel = {'[ ]'};
            app.UIAxes_95 = uiaxes(app.HistogramMatchingPanel);
            title(app.UIAxes_95, 'Result Histogram')
            app.UIAxes_95.XTick = [];
            app.UIAxes_95.XTickLabel = {'[ ]'};
            app.UIAxes_95.YTick = [];
            app.UIAxes_95.YTickLabel = {'[ ]'};
            app.OffsetPanel_12 = uipanel(app.HistogramMatchingPanel);
            app.OffsetPanel_12.Title = 'Offset';
            app.OffsetPanel_12.Visible = 'off';
            app.OffsetEditField_12Label = uilabel(app.OffsetPanel_12);
            app.OffsetEditField_12Label.Text = 'Offset';
            app.OffsetEditField_12 = uieditfield(app.OffsetPanel_12, 'numeric');
            app.New_RangePanel_12 = uipanel(app.HistogramMatchingPanel);
            app.New_RangePanel_12.Title = 'New_Range:';
            app.New_RangePanel_12.Visible = 'off';
            app.NEW_MAXEditField_12Label = uilabel(app.New_RangePanel_12);
            app.NEW_MAXEditField_12Label.Text = 'NEW_MAX';
            app.NEW_MAXEditField_12 = uieditfield(app.New_RangePanel_12, 'numeric');
            app.NEW_MINEditField_12Label = uilabel(app.New_RangePanel_12);
            app.NEW_MINEditField_12Label.Text = 'NEW_MIN';
            app.NEW_MINEditField_12 = uieditfield(app.New_RangePanel_12, 'numeric');
            app.GammaPanel_12 = uipanel(app.HistogramMatchingPanel);
            app.GammaPanel_12.Title = 'Gamma :)';
            app.GammaPanel_12.Visible = 'off';
            app.GammaEditField_12Label = uilabel(app.GammaPanel_12);
            app.GammaEditField_12Label.Text = 'Gamma:';
            app.GammaEditField_12 = uieditfield(app.GammaPanel_12, 'numeric');
            app.UIAxes_96 = uiaxes(app.HistogramMatchingPanel);
            title(app.UIAxes_96, 'Image_2')
            app.UIAxes_96.XTick = [];
            app.UIAxes_96.XTickLabel = {'[ ]'};
            app.UIAxes_96.YTick = [];
            app.UIAxes_96.YTickLabel = {'[ ]'};
            app.UIAxes_97 = uiaxes(app.HistogramMatchingPanel);
            title(app.UIAxes_97, 'Histogram_1')
            app.UIAxes_97.XTick = [];
            app.UIAxes_97.XTickLabel = {'[ ]'};
            app.UIAxes_97.YTick = [];
            app.UIAxes_97.YTickLabel = {'[ ]'};
            app.UIAxes_98 = uiaxes(app.HistogramMatchingPanel);
            title(app.UIAxes_98, 'Image_1')
            app.UIAxes_98.XTick = [];
            app.UIAxes_98.XTickLabel = {'[ ]'};
            app.UIAxes_98.YTick = [];
            app.UIAxes_98.YTickLabel = {'[ ]'};
            app.UIAxes_99 = uiaxes(app.HistogramMatchingPanel);
            title(app.UIAxes_99, 'Result')
            app.UIAxes_99.XTick = [];
            app.UIAxes_99.XTickLabel = {'[ ]'};
            app.UIAxes_99.YTick = [];
            app.UIAxes_99.YTickLabel = {'[ ]'};
            app.UploadRefPhotoButton = uibutton(app.HistogramMatchingPanel, 'push');
            app.UploadRefPhotoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadRefPhotoButtonPushed, true);
            app.UploadRefPhotoButton.Icon = 'gallery.png';
            app.UploadRefPhotoButton.Text = 'Upload Ref Photo';
            app.EditField_4 = uieditfield(app.HistogramMatchingPanel, 'text');
            app.ApplyButton_4 = uibutton(app.HistogramMatchingPanel, 'push');
            app.ApplyButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_4Pushed, true);
            app.ApplyButton_4.Icon = 'check.png';
            app.ApplyButton_4.Text = 'Apply';
            app.HistogramEqualizationButton = uibutton(app.HistogramWorkSpacePanel, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.DrawingHistogramButton = uibutton(app.HistogramWorkSpacePanel, 'push');
            app.DrawingHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @DrawingHistogramButtonPushed, true);
            app.DrawingHistogramButton.Text = 'Drawing Histogram';
            app.HistogramMatchingButton = uibutton(app.HistogramWorkSpacePanel, 'push');
            app.HistogramMatchingButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramMatchingButtonPushed, true);
            app.HistogramMatchingButton.Text = 'Histogram Matching';
            app.ContrastLow_or_HighButton = uibutton(app.HistogramWorkSpacePanel, 'push');
            app.ContrastLow_or_HighButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastLow_or_HighButtonPushed, true);
            app.ContrastLow_or_HighButton.Text = 'Contrast: Low_or_High';
            app.PowerLawTransformationsButton = uibutton(app.HistogramWorkSpacePanel, 'push');
            app.PowerLawTransformationsButton.ButtonPushedFcn = createCallbackFcn(app, @PowerLawTransformationsButtonPushed, true);
            app.PowerLawTransformationsButton.Text = 'Power-Law Transformations';
            app.BrightnessadjustmentButton = uibutton(app.HistogramWorkSpacePanel, 'push');
            app.BrightnessadjustmentButton.ButtonPushedFcn = createCallbackFcn(app, @BrightnessadjustmentButtonPushed, true);
            app.BrightnessadjustmentButton.Text = 'Brightness adjustment';
            app.ImageArithmeticWorkSpacePanel = uipanel(app.ImageProcessingAppUIFigure);
            app.ImageArithmeticWorkSpacePanel.Title = 'Image Arithmetic Work Space';
            app.ImageArithmeticWorkSpacePanel.Visible = 'off';
            app.UploadPhotoButton_4 = uibutton(app.ImageArithmeticWorkSpacePanel, 'push');
            app.UploadPhotoButton_4.ButtonPushedFcn = createCallbackFcn(app, @UploadPhotoButton_4Pushed, true);
            app.UploadPhotoButton_4.Icon = 'gallery.png';
            app.UploadPhotoButton_4.Text = 'Upload Photo';
            app.EditField_5 = uieditfield(app.ImageArithmeticWorkSpacePanel, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.histogramFunctionsPanel_4 = uipanel(app.ImageArithmeticWorkSpacePanel);
            app.histogramFunctionsPanel_4.Title = 'Direct Mapping: Zero - Order';
            app.histogramFunctionsPanel_4.Visible = 'off';
            app.UIAxes_29 = uiaxes(app.histogramFunctionsPanel_4);
            title(app.UIAxes_29, 'Original Photo')
            app.UIAxes_29.XTick = [];
            app.UIAxes_29.XTickLabel = {'[ ]'};
            app.UIAxes_29.YTick = [];
            app.UIAxes_29.YTickLabel = {'[ ]'};
            app.UIAxes_30 = uiaxes(app.histogramFunctionsPanel_4);
            title(app.UIAxes_30, 'Resized Photo')
            app.UIAxes_30.XTick = [];
            app.UIAxes_30.XTickLabel = {'[ ]'};
            app.UIAxes_30.YTick = [];
            app.UIAxes_30.YTickLabel = {'[ ]'};
            app.FactorEditField_7Label = uilabel(app.histogramFunctionsPanel_4);
            app.FactorEditField_7Label.Text = 'Factor';
            app.FactorEditField_7 = uieditfield(app.histogramFunctionsPanel_4, 'numeric');
            app.FactorEditField_7.ValueDisplayFormat = '%.0f';
            app.ResizeButton_13 = uibutton(app.histogramFunctionsPanel_4, 'push');
            app.ResizeButton_13.Text = 'Resize';
            app.DirectMappingOneOrderPanel_4 = uipanel(app.ImageArithmeticWorkSpacePanel);
            app.DirectMappingOneOrderPanel_4.Title = 'Direct Mapping: One - Order';
            app.DirectMappingOneOrderPanel_4.Visible = 'off';
            app.UIAxes_31 = uiaxes(app.DirectMappingOneOrderPanel_4);
            title(app.UIAxes_31, 'Original Photo')
            app.UIAxes_31.XTick = [];
            app.UIAxes_31.XTickLabel = {'[ ]'};
            app.UIAxes_31.YTick = [];
            app.UIAxes_31.YTickLabel = {'[ ]'};
            app.UIAxes_32 = uiaxes(app.DirectMappingOneOrderPanel_4);
            title(app.UIAxes_32, 'Resized Photo')
            app.UIAxes_32.XTick = [];
            app.UIAxes_32.XTickLabel = {'[ ]'};
            app.UIAxes_32.YTick = [];
            app.UIAxes_32.YTickLabel = {'[ ]'};
            app.FactorEditField_8Label = uilabel(app.DirectMappingOneOrderPanel_4);
            app.FactorEditField_8Label.Text = 'Factor';
            app.FactorEditField_8 = uieditfield(app.DirectMappingOneOrderPanel_4, 'numeric');
            app.FactorEditField_8.ValueDisplayFormat = '%.0f';
            app.ResizeButton_14 = uibutton(app.DirectMappingOneOrderPanel_4, 'push');
            app.ResizeButton_14.Text = 'Resize';
            app.ReverseMappingOneOrderPanel_4 = uipanel(app.ImageArithmeticWorkSpacePanel);
            app.ReverseMappingOneOrderPanel_4.Title = 'Reverse Mapping: One - Order';
            app.ReverseMappingOneOrderPanel_4.Visible = 'off';
            app.UIAxes_33 = uiaxes(app.ReverseMappingOneOrderPanel_4);
            title(app.UIAxes_33, 'Original Photo')
            app.UIAxes_33.XTick = [];
            app.UIAxes_33.XTickLabel = {'[ ]'};
            app.UIAxes_33.YTick = [];
            app.UIAxes_33.YTickLabel = {'[ ]'};
            app.UIAxes_34 = uiaxes(app.ReverseMappingOneOrderPanel_4);
            title(app.UIAxes_34, 'Resized Photo')
            app.UIAxes_34.XTick = [];
            app.UIAxes_34.XTickLabel = {'[ ]'};
            app.UIAxes_34.YTick = [];
            app.UIAxes_34.YTickLabel = {'[ ]'};
            app.ResizeButton_15 = uibutton(app.ReverseMappingOneOrderPanel_4, 'push');
            app.ResizeButton_15.Text = 'Resize';
            app.RowFactorEditField_7Label = uilabel(app.ReverseMappingOneOrderPanel_4);
            app.RowFactorEditField_7Label.Text = 'Row Factor';
            app.RowFactorEditField_7 = uieditfield(app.ReverseMappingOneOrderPanel_4, 'numeric');
            app.RowFactorEditField_7.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_7Label = uilabel(app.ReverseMappingOneOrderPanel_4);
            app.ColFactorEditField_7Label.Text = 'Col Factor';
            app.ColFactorEditField_7 = uieditfield(app.ReverseMappingOneOrderPanel_4, 'numeric');
            app.ColFactorEditField_7.ValueDisplayFormat = '%.0f';
            app.ReverseMappingZeroOrderPanel_4 = uipanel(app.ImageArithmeticWorkSpacePanel);
            app.ReverseMappingZeroOrderPanel_4.Title = 'Reverse Mapping: Zero - Order';
            app.ReverseMappingZeroOrderPanel_4.Visible = 'off';
            app.UIAxes_35 = uiaxes(app.ReverseMappingZeroOrderPanel_4);
            title(app.UIAxes_35, 'Original Photo')
            app.UIAxes_35.XTick = [];
            app.UIAxes_35.XTickLabel = {'[ ]'};
            app.UIAxes_35.YTick = [];
            app.UIAxes_35.YTickLabel = {'[ ]'};
            app.UIAxes_36 = uiaxes(app.ReverseMappingZeroOrderPanel_4);
            title(app.UIAxes_36, 'Resized Photo')
            app.UIAxes_36.XTick = [];
            app.UIAxes_36.XTickLabel = {'[ ]'};
            app.UIAxes_36.YTick = [];
            app.UIAxes_36.YTickLabel = {'[ ]'};
            app.ResizeButton_16 = uibutton(app.ReverseMappingZeroOrderPanel_4, 'push');
            app.ResizeButton_16.Text = 'Resize';
            app.RowFactorEditField_8Label = uilabel(app.ReverseMappingZeroOrderPanel_4);
            app.RowFactorEditField_8Label.Text = 'Row Factor';
            app.RowFactorEditField_8 = uieditfield(app.ReverseMappingZeroOrderPanel_4, 'numeric');
            app.RowFactorEditField_8.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_8Label = uilabel(app.ReverseMappingZeroOrderPanel_4);
            app.ColFactorEditField_8Label.Text = 'Col Factor';
            app.ColFactorEditField_8 = uieditfield(app.ReverseMappingZeroOrderPanel_4, 'numeric');
            app.ColFactorEditField_8.ValueDisplayFormat = '%.0f';
            app.ImageAdditionSubtractionPanel = uipanel(app.ImageArithmeticWorkSpacePanel);
            app.ImageAdditionSubtractionPanel.Title = 'Image Addition / Subtraction:';
            app.ImageAdditionSubtractionPanel.Visible = 'off';
            app.UIAxes_37 = uiaxes(app.ImageAdditionSubtractionPanel);
            title(app.UIAxes_37, ' Result')
            app.UIAxes_37.XTick = [];
            app.UIAxes_37.XTickLabel = {'[ ]'};
            app.UIAxes_37.YTick = [];
            app.UIAxes_37.YTickLabel = {'[ ]'};
            app.OffsetPanel_2 = uipanel(app.ImageAdditionSubtractionPanel);
            app.OffsetPanel_2.Title = 'Offset';
            app.OffsetPanel_2.Visible = 'off';
            app.OffsetEditField_2Label = uilabel(app.OffsetPanel_2);
            app.OffsetEditField_2Label.Text = 'Offset';
            app.OffsetEditField_2 = uieditfield(app.OffsetPanel_2, 'numeric');
            app.New_RangePanel_2 = uipanel(app.ImageAdditionSubtractionPanel);
            app.New_RangePanel_2.Title = 'New_Range:';
            app.New_RangePanel_2.Visible = 'off';
            app.NEW_MAXEditField_2Label = uilabel(app.New_RangePanel_2);
            app.NEW_MAXEditField_2Label.Text = 'NEW_MAX';
            app.NEW_MAXEditField_2 = uieditfield(app.New_RangePanel_2, 'numeric');
            app.NEW_MINEditField_2Label = uilabel(app.New_RangePanel_2);
            app.NEW_MINEditField_2Label.Text = 'NEW_MIN';
            app.NEW_MINEditField_2 = uieditfield(app.New_RangePanel_2, 'numeric');
            app.GammaPanel_2 = uipanel(app.ImageAdditionSubtractionPanel);
            app.GammaPanel_2.Title = 'Gamma :)';
            app.GammaPanel_2.Visible = 'off';
            app.GammaEditField_2Label = uilabel(app.GammaPanel_2);
            app.GammaEditField_2Label.Text = 'Gamma:';
            app.GammaEditField_2 = uieditfield(app.GammaPanel_2, 'numeric');
            app.EditField_6 = uieditfield(app.ImageAdditionSubtractionPanel, 'text');
            app.EditField_6.Visible = 'off';
            app.UploadPhoto_2Button = uibutton(app.ImageAdditionSubtractionPanel, 'push');
            app.UploadPhoto_2Button.ButtonPushedFcn = createCallbackFcn(app, @UploadPhoto_2ButtonPushed, true);
            app.UploadPhoto_2Button.Icon = 'gallery.png';
            app.UploadPhoto_2Button.Text = 'Upload Photo_2';
            app.EditField_7 = uieditfield(app.ImageAdditionSubtractionPanel, 'text');
            app.ApplyButton_8 = uibutton(app.ImageAdditionSubtractionPanel, 'push');
            app.ApplyButton_8.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_8Pushed, true);
            app.ApplyButton_8.Icon = 'check.png';
            app.ApplyButton_8.Visible = 'off';
            app.ApplyButton_8.Text = 'Apply';
            app.UIAxes_39 = uiaxes(app.ImageAdditionSubtractionPanel);
            title(app.UIAxes_39, 'First Image')
            app.UIAxes_39.XTick = [];
            app.UIAxes_39.XTickLabel = {'[ ]'};
            app.UIAxes_39.YTick = [];
            app.UIAxes_39.YTickLabel = {'[ ]'};
            app.UIAxes_38 = uiaxes(app.ImageAdditionSubtractionPanel);
            title(app.UIAxes_38, 'Second Image')
            app.UIAxes_38.XTick = [];
            app.UIAxes_38.XTickLabel = {'[ ]'};
            app.UIAxes_38.YTick = [];
            app.UIAxes_38.YTickLabel = {'[ ]'};
            app.ApplyButton_9 = uibutton(app.ImageAdditionSubtractionPanel, 'push');
            app.ApplyButton_9.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_9Pushed, true);
            app.ApplyButton_9.Icon = 'check.png';
            app.ApplyButton_9.Visible = 'off';
            app.ApplyButton_9.Text = 'Apply';
            app.SubtractionButton = uibutton(app.ImageArithmeticWorkSpacePanel, 'push');
            app.SubtractionButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractionButtonPushed, true);
            app.SubtractionButton.Text = 'Subtraction';
            app.AdditionButton = uibutton(app.ImageArithmeticWorkSpacePanel, 'push');
            app.AdditionButton.ButtonPushedFcn = createCallbackFcn(app, @AdditionButtonPushed, true);
            app.AdditionButton.Text = 'Addition';
            app.ImageNegativeButton = uibutton(app.ImageArithmeticWorkSpacePanel, 'push');
            app.ImageNegativeButton.ButtonPushedFcn = createCallbackFcn(app, @ImageNegativeButtonPushed, true);
            app.ImageNegativeButton.Text = 'Image Negative';
            app.QuantizationButton = uibutton(app.ImageArithmeticWorkSpacePanel, 'push');
            app.QuantizationButton.ButtonPushedFcn = createCallbackFcn(app, @QuantizationButtonPushed, true);
            app.QuantizationButton.Text = 'Quantization';
            app.ImageArithmeticPanel = uipanel(app.ImageArithmeticWorkSpacePanel);
            app.ImageArithmeticPanel.Title = 'Image Arithmetic:';
            app.ImageArithmeticPanel.Visible = 'off';
            app.OffsetPanel_3 = uipanel(app.ImageArithmeticPanel);
            app.OffsetPanel_3.Title = 'Offset';
            app.OffsetPanel_3.Visible = 'off';
            app.OffsetEditField_3Label = uilabel(app.OffsetPanel_3);
            app.OffsetEditField_3Label.Text = 'Offset';
            app.OffsetEditField_3 = uieditfield(app.OffsetPanel_3, 'numeric');
            app.New_RangePanel_3 = uipanel(app.ImageArithmeticPanel);
            app.New_RangePanel_3.Title = 'New_Range:';
            app.New_RangePanel_3.Visible = 'off';
            app.NEW_MAXEditField_3Label = uilabel(app.New_RangePanel_3);
            app.NEW_MAXEditField_3Label.Text = 'NEW_MAX';
            app.NEW_MAXEditField_3 = uieditfield(app.New_RangePanel_3, 'numeric');
            app.NEW_MINEditField_3Label = uilabel(app.New_RangePanel_3);
            app.NEW_MINEditField_3Label.Text = 'NEW_MIN';
            app.NEW_MINEditField_3 = uieditfield(app.New_RangePanel_3, 'numeric');
            app.GammaPanel_3 = uipanel(app.ImageArithmeticPanel);
            app.GammaPanel_3.Title = 'Gamma :)';
            app.GammaPanel_3.Visible = 'off';
            app.GammaEditField_3Label = uilabel(app.GammaPanel_3);
            app.GammaEditField_3Label.Text = 'Gamma:';
            app.GammaEditField_3 = uieditfield(app.GammaPanel_3, 'numeric');
            app.EditField_8 = uieditfield(app.ImageArithmeticPanel, 'text');
            app.EditField_8.Visible = 'off';
            app.BitsPanel = uipanel(app.ImageArithmeticPanel);
            app.BitsPanel.Title = 'Bits:';
            app.BitsPanel.Visible = 'off';
            app.NoOfBitsEditFieldLabel = uilabel(app.BitsPanel);
            app.NoOfBitsEditFieldLabel.Text = 'No.Of .Bits';
            app.NoOfBitsEditField = uieditfield(app.BitsPanel, 'numeric');
            app.NoOfBitsEditField.Limits = [0 Inf];
            app.ApplyButton_10 = uibutton(app.ImageArithmeticPanel, 'push');
            app.ApplyButton_10.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_10Pushed, true);
            app.ApplyButton_10.Icon = 'check.png';
            app.ApplyButton_10.Visible = 'off';
            app.ApplyButton_10.Text = 'Apply';
            app.UIAxes_40 = uiaxes(app.ImageArithmeticPanel);
            title(app.UIAxes_40, 'Original ')
            app.UIAxes_40.XTick = [];
            app.UIAxes_40.XTickLabel = {'[ ]'};
            app.UIAxes_40.YTick = [];
            app.UIAxes_40.YTickLabel = {'[ ]'};
            app.UIAxes_41 = uiaxes(app.ImageArithmeticPanel);
            title(app.UIAxes_41, 'Result')
            app.UIAxes_41.XTick = [];
            app.UIAxes_41.XTickLabel = {'[ ]'};
            app.UIAxes_41.YTick = [];
            app.UIAxes_41.YTickLabel = {'[ ]'};
            app.SmoothingWorkSpacePanel = uipanel(app.ImageProcessingAppUIFigure);
            app.SmoothingWorkSpacePanel.Title = 'Smoothing Work Space';
            app.SmoothingWorkSpacePanel.Visible = 'off';
            app.UploadPhotoButton_5 = uibutton(app.SmoothingWorkSpacePanel, 'push');
            app.UploadPhotoButton_5.ButtonPushedFcn = createCallbackFcn(app, @UploadPhotoButton_5Pushed, true);
            app.UploadPhotoButton_5.Icon = 'gallery.png';
            app.UploadPhotoButton_5.Text = 'Upload Photo';
            app.EditField_9 = uieditfield(app.SmoothingWorkSpacePanel, 'text');
            app.histogramFunctionsPanel_5 = uipanel(app.SmoothingWorkSpacePanel);
            app.histogramFunctionsPanel_5.Title = 'Direct Mapping: Zero - Order';
            app.histogramFunctionsPanel_5.Visible = 'off';
            app.UIAxes_42 = uiaxes(app.histogramFunctionsPanel_5);
            title(app.UIAxes_42, 'Original Photo')
            app.UIAxes_42.XTick = [];
            app.UIAxes_42.XTickLabel = {'[ ]'};
            app.UIAxes_42.YTick = [];
            app.UIAxes_42.YTickLabel = {'[ ]'};
            app.UIAxes_43 = uiaxes(app.histogramFunctionsPanel_5);
            title(app.UIAxes_43, 'Resized Photo')
            app.UIAxes_43.XTick = [];
            app.UIAxes_43.XTickLabel = {'[ ]'};
            app.UIAxes_43.YTick = [];
            app.UIAxes_43.YTickLabel = {'[ ]'};
            app.FactorEditField_9Label = uilabel(app.histogramFunctionsPanel_5);
            app.FactorEditField_9Label.Text = 'Factor';
            app.FactorEditField_9 = uieditfield(app.histogramFunctionsPanel_5, 'numeric');
            app.FactorEditField_9.ValueDisplayFormat = '%.0f';
            app.ResizeButton_17 = uibutton(app.histogramFunctionsPanel_5, 'push');
            app.ResizeButton_17.Text = 'Resize';
            app.DirectMappingOneOrderPanel_5 = uipanel(app.SmoothingWorkSpacePanel);
            app.DirectMappingOneOrderPanel_5.Title = 'Direct Mapping: One - Order';
            app.DirectMappingOneOrderPanel_5.Visible = 'off';
            app.UIAxes_44 = uiaxes(app.DirectMappingOneOrderPanel_5);
            title(app.UIAxes_44, 'Original Photo')
            app.UIAxes_44.XTick = [];
            app.UIAxes_44.XTickLabel = {'[ ]'};
            app.UIAxes_44.YTick = [];
            app.UIAxes_44.YTickLabel = {'[ ]'};
            app.UIAxes_45 = uiaxes(app.DirectMappingOneOrderPanel_5);
            title(app.UIAxes_45, 'Resized Photo')
            app.UIAxes_45.XTick = [];
            app.UIAxes_45.XTickLabel = {'[ ]'};
            app.UIAxes_45.YTick = [];
            app.UIAxes_45.YTickLabel = {'[ ]'};
            app.FactorEditField_10Label = uilabel(app.DirectMappingOneOrderPanel_5);
            app.FactorEditField_10Label.Text = 'Factor';
            app.FactorEditField_10 = uieditfield(app.DirectMappingOneOrderPanel_5, 'numeric');
            app.FactorEditField_10.ValueDisplayFormat = '%.0f';
            app.ResizeButton_18 = uibutton(app.DirectMappingOneOrderPanel_5, 'push');
            app.ResizeButton_18.Text = 'Resize';
            app.ReverseMappingOneOrderPanel_5 = uipanel(app.SmoothingWorkSpacePanel);
            app.ReverseMappingOneOrderPanel_5.Title = 'Reverse Mapping: One - Order';
            app.ReverseMappingOneOrderPanel_5.Visible = 'off';
            app.UIAxes_46 = uiaxes(app.ReverseMappingOneOrderPanel_5);
            title(app.UIAxes_46, 'Original Photo')
            app.UIAxes_46.XTick = [];
            app.UIAxes_46.XTickLabel = {'[ ]'};
            app.UIAxes_46.YTick = [];
            app.UIAxes_46.YTickLabel = {'[ ]'};
            app.UIAxes_47 = uiaxes(app.ReverseMappingOneOrderPanel_5);
            title(app.UIAxes_47, 'Resized Photo')
            app.UIAxes_47.XTick = [];
            app.UIAxes_47.XTickLabel = {'[ ]'};
            app.UIAxes_47.YTick = [];
            app.UIAxes_47.YTickLabel = {'[ ]'};
            app.ResizeButton_19 = uibutton(app.ReverseMappingOneOrderPanel_5, 'push');
            app.ResizeButton_19.Text = 'Resize';
            app.RowFactorEditField_9Label = uilabel(app.ReverseMappingOneOrderPanel_5);
            app.RowFactorEditField_9Label.Text = 'Row Factor';
            app.RowFactorEditField_9 = uieditfield(app.ReverseMappingOneOrderPanel_5, 'numeric');
            app.RowFactorEditField_9.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_9Label = uilabel(app.ReverseMappingOneOrderPanel_5);
            app.ColFactorEditField_9Label.Text = 'Col Factor';
            app.ColFactorEditField_9 = uieditfield(app.ReverseMappingOneOrderPanel_5, 'numeric');
            app.ColFactorEditField_9.ValueDisplayFormat = '%.0f';
            app.ReverseMappingZeroOrderPanel_5 = uipanel(app.SmoothingWorkSpacePanel);
            app.ReverseMappingZeroOrderPanel_5.Title = 'Reverse Mapping: Zero - Order';
            app.ReverseMappingZeroOrderPanel_5.Visible = 'off';
            app.UIAxes_48 = uiaxes(app.ReverseMappingZeroOrderPanel_5);
            title(app.UIAxes_48, 'Original Photo')
            app.UIAxes_48.XTick = [];
            app.UIAxes_48.XTickLabel = {'[ ]'};
            app.UIAxes_48.YTick = [];
            app.UIAxes_48.YTickLabel = {'[ ]'};
            app.UIAxes_49 = uiaxes(app.ReverseMappingZeroOrderPanel_5);
            title(app.UIAxes_49, 'Resized Photo')
            app.UIAxes_49.XTick = [];
            app.UIAxes_49.XTickLabel = {'[ ]'};
            app.UIAxes_49.YTick = [];
            app.UIAxes_49.YTickLabel = {'[ ]'};
            app.ResizeButton_20 = uibutton(app.ReverseMappingZeroOrderPanel_5, 'push');
            app.ResizeButton_20.Text = 'Resize';
            app.RowFactorEditField_10Label = uilabel(app.ReverseMappingZeroOrderPanel_5);
            app.RowFactorEditField_10Label.Text = 'Row Factor';
            app.RowFactorEditField_10 = uieditfield(app.ReverseMappingZeroOrderPanel_5, 'numeric');
            app.RowFactorEditField_10.ValueDisplayFormat = '%.0f';
            app.ColFactorEditField_10Label = uilabel(app.ReverseMappingZeroOrderPanel_5);
            app.ColFactorEditField_10Label.Text = 'Col Factor';
            app.ColFactorEditField_10 = uieditfield(app.ReverseMappingZeroOrderPanel_5, 'numeric');
            app.ColFactorEditField_10.ValueDisplayFormat = '%.0f';
            app.ImageAdditionSubtractionPanel_2 = uipanel(app.SmoothingWorkSpacePanel);
            app.ImageAdditionSubtractionPanel_2.Title = 'Image Addition / Subtraction:';
            app.ImageAdditionSubtractionPanel_2.Visible = 'off';
            app.UIAxes_50 = uiaxes(app.ImageAdditionSubtractionPanel_2);
            title(app.UIAxes_50, ' Result')
            app.UIAxes_50.XTick = [];
            app.UIAxes_50.XTickLabel = {'[ ]'};
            app.UIAxes_50.YTick = [];
            app.UIAxes_50.YTickLabel = {'[ ]'};
            app.UIAxes_51 = uiaxes(app.ImageAdditionSubtractionPanel_2);
            title(app.UIAxes_51, 'Second Image')
            app.UIAxes_51.XTick = [];
            app.UIAxes_51.XTickLabel = {'[ ]'};
            app.UIAxes_51.YTick = [];
            app.UIAxes_51.YTickLabel = {'[ ]'};
            app.OffsetPanel_4 = uipanel(app.ImageAdditionSubtractionPanel_2);
            app.OffsetPanel_4.Title = 'Offset';
            app.OffsetPanel_4.Visible = 'off';
            app.OffsetEditField_4Label = uilabel(app.OffsetPanel_4);
            app.OffsetEditField_4Label.Text = 'Offset';
            app.OffsetEditField_4 = uieditfield(app.OffsetPanel_4, 'numeric');
            app.New_RangePanel_4 = uipanel(app.ImageAdditionSubtractionPanel_2);
            app.New_RangePanel_4.Title = 'New_Range:';
            app.New_RangePanel_4.Visible = 'off';
            app.NEW_MAXEditField_4Label = uilabel(app.New_RangePanel_4);
            app.NEW_MAXEditField_4Label.Text = 'NEW_MAX';
            app.NEW_MAXEditField_4 = uieditfield(app.New_RangePanel_4, 'numeric');
            app.NEW_MINEditField_4Label = uilabel(app.New_RangePanel_4);
            app.NEW_MINEditField_4Label.Text = 'NEW_MIN';
            app.NEW_MINEditField_4 = uieditfield(app.New_RangePanel_4, 'numeric');
            app.GammaPanel_4 = uipanel(app.ImageAdditionSubtractionPanel_2);
            app.GammaPanel_4.Title = 'Gamma :)';
            app.GammaPanel_4.Visible = 'off';
            app.GammaEditField_4Label = uilabel(app.GammaPanel_4);
            app.GammaEditField_4Label.Text = 'Gamma:';
            app.GammaEditField_4 = uieditfield(app.GammaPanel_4, 'numeric');
            app.EditField_10 = uieditfield(app.ImageAdditionSubtractionPanel_2, 'text');
            app.EditField_10.Visible = 'off';
            app.EditField_11 = uieditfield(app.ImageAdditionSubtractionPanel_2, 'text');
            app.UIAxes_52 = uiaxes(app.ImageAdditionSubtractionPanel_2);
            title(app.UIAxes_52, 'First Image')
            app.UIAxes_52.XTick = [];
            app.UIAxes_52.XTickLabel = {'[ ]'};
            app.UIAxes_52.YTick = [];
            app.UIAxes_52.YTickLabel = {'[ ]'};
            app.WeightedFilterButton = uibutton(app.SmoothingWorkSpacePanel, 'push');
            app.WeightedFilterButton.ButtonPushedFcn = createCallbackFcn(app, @WeightedFilterButtonPushed, true);
            app.WeightedFilterButton.Text = 'Weighted Filter';
            app.MeanFilterButton = uibutton(app.SmoothingWorkSpacePanel, 'push');
            app.MeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanFilterButtonPushed, true);
            app.MeanFilterButton.Text = 'Mean Filter';
            app.MedianFilterButton = uibutton(app.SmoothingWorkSpacePanel, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.MaxFilterButton = uibutton(app.SmoothingWorkSpacePanel, 'push');
            app.MaxFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MaxFilterButtonPushed, true);
            app.MaxFilterButton.Text = 'Max Filter';
            app.BitsPanel_2 = uipanel(app.SmoothingWorkSpacePanel);
            app.BitsPanel_2.Title = 'Bits:';
            app.BitsPanel_2.Visible = 'off';
            app.NoOfBitsEditField_2Label = uilabel(app.BitsPanel_2);
            app.NoOfBitsEditField_2Label.Text = 'No.Of .Bits';
            app.NoOfBitsEditField_2 = uieditfield(app.BitsPanel_2, 'numeric');
            app.NoOfBitsEditField_2.Limits = [0 Inf];
            app.MinFilterButton = uibutton(app.SmoothingWorkSpacePanel, 'push');
            app.MinFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MinFilterButtonPushed, true);
            app.MinFilterButton.Text = 'Min Filter';
            app.SmoothingPanel = uipanel(app.SmoothingWorkSpacePanel);
            app.SmoothingPanel.Title = 'Smoothing:';
            app.UIAxes_53 = uiaxes(app.SmoothingPanel);
            title(app.UIAxes_53, 'Original ')
            app.UIAxes_53.XTick = [];
            app.UIAxes_53.XTickLabel = {'[ ]'};
            app.UIAxes_53.YTick = [];
            app.UIAxes_53.YTickLabel = {'[ ]'};
            app.UIAxes_54 = uiaxes(app.SmoothingPanel);
            title(app.UIAxes_54, 'Result')
            app.UIAxes_54.XTick = [];
            app.UIAxes_54.XTickLabel = {'[ ]'};
            app.UIAxes_54.YTick = [];
            app.UIAxes_54.YTickLabel = {'[ ]'};
            app.OffsetPanel_5 = uipanel(app.SmoothingPanel);
            app.OffsetPanel_5.Title = 'Offset';
            app.OffsetPanel_5.Visible = 'off';
            app.OffsetEditField_5Label = uilabel(app.OffsetPanel_5);
            app.OffsetEditField_5Label.Text = 'Offset';
            app.OffsetEditField_5 = uieditfield(app.OffsetPanel_5, 'numeric');
            app.New_RangePanel_5 = uipanel(app.SmoothingPanel);
            app.New_RangePanel_5.Title = 'New_Range:';
            app.New_RangePanel_5.Visible = 'off';
            app.NEW_MAXEditField_5Label = uilabel(app.New_RangePanel_5);
            app.NEW_MAXEditField_5Label.Text = 'NEW_MAX';
            app.NEW_MAXEditField_5 = uieditfield(app.New_RangePanel_5, 'numeric');
            app.NEW_MINEditField_5Label = uilabel(app.New_RangePanel_5);
            app.NEW_MINEditField_5Label.Text = 'NEW_MIN';
            app.NEW_MINEditField_5 = uieditfield(app.New_RangePanel_5, 'numeric');
            app.GammaPanel_5 = uipanel(app.SmoothingPanel);
            app.GammaPanel_5.Title = 'Gamma :)';
            app.GammaPanel_5.Visible = 'off';
            app.GammaEditField_5Label = uilabel(app.GammaPanel_5);
            app.GammaEditField_5Label.Text = 'Gamma:';
            app.GammaEditField_5 = uieditfield(app.GammaPanel_5, 'numeric');
            app.MaskPanel = uipanel(app.SmoothingPanel);
            app.MaskPanel.Title = 'Mask:';
            app.MaskPanel.Visible = 'off';
            app.MaskSizeLabel = uilabel(app.MaskPanel);
            app.MaskSizeLabel.Text = 'Mask Size:';
            app.MaskSizeEditField = uieditfield(app.MaskPanel, 'numeric');
            app.MaskSizeEditField.Limits = [1 Inf];
            app.MaskSizeEditField.ValueDisplayFormat = '%.0f';
            app.MaskSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MaskSizeEditFieldValueChanged, true);
            app.MaskSizeEditField.Value = 1;
            app.SigmaPanel = uipanel(app.SmoothingPanel);
            app.SigmaPanel.Title = 'Sigma:';
            app.SigmaPanel.Visible = 'off';
            app.SigmaEditFieldLabel = uilabel(app.SigmaPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma σ:';
            app.SigmaEditField = uieditfield(app.SigmaPanel, 'numeric');
            app.SigmaEditField.Limits = [0 Inf];

            app.ApplyButton_11 = uibutton(app.SmoothingPanel, 'push');
            app.ApplyButton_11.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_11Pushed, true);
            app.ApplyButton_11.Icon = 'check.png';
            app.ApplyButton_11.Visible = 'off';
            app.ApplyButton_11.Text = 'Apply';
            app.ApplyButton_12 = uibutton(app.SmoothingPanel, 'push');
            app.ApplyButton_12.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_12Pushed, true);
            app.ApplyButton_12.Icon = 'check.png';
            app.ApplyButton_12.Visible = 'off';
            app.ApplyButton_12.Text = 'Apply';
            app.ApplyButton_13 = uibutton(app.SmoothingPanel, 'push');
            app.ApplyButton_13.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_13Pushed, true);
            app.ApplyButton_13.Icon = 'check.png';
            app.ApplyButton_13.Visible = 'off';
            app.ApplyButton_13.Text = 'Apply';
            app.ApplyButton_14 = uibutton(app.SmoothingPanel, 'push');
            app.ApplyButton_14.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_14Pushed, true);
            app.ApplyButton_14.Icon = 'check.png';
            app.ApplyButton_14.Visible = 'off';
            app.ApplyButton_14.Text = 'Apply';
            app.ApplyButton_15 = uibutton(app.SmoothingPanel, 'push');
            app.ApplyButton_15.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_15Pushed, true);
            app.ApplyButton_15.Icon = 'check.png';
            app.ApplyButton_15.Visible = 'off';
            app.ApplyButton_15.Text = 'Apply';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUIImplementation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageProcessingAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageProcessingAppUIFigure)
        end
    end
end